classdef particle_tracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIPanel                     matlab.ui.Figure
        GridLayout2                 matlab.ui.container.GridLayout
        RightPanel                  matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        sldLevelRange               matlab.ui.control.RangeSlider
        LevelrangeLabel             matlab.ui.control.Label
        sldFrame                    matlab.ui.control.Slider
        FrameLabel                  matlab.ui.control.Label
        spnFrame                    matlab.ui.control.Spinner
        ax                          matlab.ui.control.UIAxes
        pnlLocatingTracking         matlab.ui.container.Panel
        locatingTrackingGridLayout  matlab.ui.container.GridLayout
        chkPkfnd                    matlab.ui.control.CheckBox
        sldMinBrightness            matlab.ui.control.Slider
        spnMinBrightness            matlab.ui.control.Spinner
        edtBPassDia                 matlab.ui.control.NumericEditField
        edtPkfndDia                 matlab.ui.control.NumericEditField
        btnSaveData                 matlab.ui.control.Button
        btnClearTracks              matlab.ui.control.Button
        chkDriftCanceling           matlab.ui.control.CheckBox
        btnTrack                    matlab.ui.control.Button
        btnPoslst                   matlab.ui.control.Button
        lblMinBrightness            matlab.ui.control.Label
        edtGood                     matlab.ui.control.NumericEditField
        MinlengthLabel              matlab.ui.control.Label
        edtMem                      matlab.ui.control.NumericEditField
        MemoryLabel                 matlab.ui.control.Label
        edtMaxDisp                  matlab.ui.control.NumericEditField
        MaxDisppxLabel              matlab.ui.control.Label
        chkBPass                    matlab.ui.control.CheckBox
        pnlLoadFile                 matlab.ui.container.Panel
        loadFileGridLayout          matlab.ui.container.GridLayout
        tblMetaList                 matlab.ui.control.Table
        txtMessage                  matlab.ui.control.TextArea
        btgFileTypes                matlab.ui.container.ButtonGroup
        optFITS                     matlab.ui.control.RadioButton
        optND2                      matlab.ui.control.RadioButton
        btnLoadFile                 matlab.ui.control.Button
    end


    properties (Access = private)
        CurrentPath=pwd % current path of browsing files
        % preview=figure('Name','Preview','Visible','off',...
        %     'Position',[640,0,1,1]);
        % ax=axes;
        current_img;
        R
        poslst
        h_pk
        h_cnd
        N_frame
        br
        meanNoise
        stdNoise
        snr
        tr
        dt
        PixelSize
        PixelSizeUnit

        blnBPass
        BPassDia
        PkfndDia
        MinBrightness
        MaxDisp
        Memory
        MinLength


        % blnCloseApp=false;
        drift_profile % Description
    end

    methods (Access = private)


        function results = replot(app)

            % Save current zoom state unless the axes contral is empty
            if ~isempty(app.ax.Children)
                XLim=app.ax.XLim;
                YLim=app.ax.YLim;
                bln_ax_empty=false;
            else
                bln_ax_empty=true;
            end

            % Obtain the intensity profile of the required plane
            switch app.btgFileTypes.SelectedObject
                case app.optND2
                    img=bfGetPlane(app.R,app.sldFrame.Value); % should be uint16.
                case app.optFITS
                    % determine which cell the required frame is at and the
                    % corresponding index of plane in that cell
                    cumulativeSum=0;
                    k=NaN;
                    img_ind=NaN;
                    for i=1:numel(app.R)

                        if app.sldFrame.Value<=cumulativeSum+app.R{i,2}
                            k=i;
                            img_ind=app.sldFrame.Value-cumulativeSum;
                            break;
                        end
                        cumulativeSum=cumulativeSum+app.R{i,2};
                    end

                    img=bfGetPlane(app.R{k,1},img_ind);
                    switch class(img)
                        case 'int16'
                            img=uint16(img+32768); % convert int16 format into uint16
                    end
            end

            % bpass if required
            if app.chkBPass.Value
                if app.edtBPassDia.Value==0
                    img=bpass(img,2,false);
                else
                    img=bpass(img,2,app.edtBPassDia.Value);
                end
            end

            % Now the image to display is ready. Next we need to inherit
            % the display setting of the current image to the new image.

            cla(app.ax);
            % inherit level range
            if ~isequal(img,app.current_img)
                if isempty(app.current_img)
                    % display the image the first time after opening file
                    % app.img=img;
                    max_intensity=double(max(img(:)));
                    app.sldLevelRange.Limits=[0,ceil(2.*max_intensity)];
                    app.sldLevelRange.Value=[0,ceil(2.*max_intensity)];
                    imshow(img,app.sldLevelRange.Value,'Parent',app.ax)
                    [X,Y]=size(img);
                    app.ax.XLim=[1,Y];
                    app.ax.YLim=[1,X];


                else
                    % if img is changed update level range
                    old_level=app.sldLevelRange.Value;
                    % app.img=img;
                    max_intensity=double(max(img(:)));
                    app.sldLevelRange.Limits=[0,ceil(2.*max_intensity)];
                    app.spnMinBrightness.Limits=app.sldLevelRange.Limits;


                    if old_level(1)>=app.sldLevelRange.Limits(2) || ...
                            old_level(2)<=app.sldLevelRange.Limits(1)
                        % in case the range of current image has no
                        % interset with the old one
                        app.sldLevelRange.Value=app.sldLevelRange.Limits;
                        imshow(img,app.sldLevelRange.Limits,'Parent',app.ax);
                    else
                        if old_level(2)<=app.sldLevelRange.Limits(2)
                            app.sldLevelRange.Value(2)=old_level(2);
                        end
                        if old_level(1)>=app.sldLevelRange.Limits(1)
                            app.sldLevelRange.Value(1)=old_level(1);
                        end
                        imshow(img,app.sldLevelRange.Value,'Parent',app.ax);
                    end
                end
                app.sldLevelRange.Tooltip=[num2str(app.sldLevelRange.Value(1)),'~',num2str(app.sldLevelRange.Value(2))];
            else
                % if img is not changed show with current range
                % app.img=img;
                imshow(img,app.sldLevelRange.Value,'Parent',app.ax);
            end


            % Save current zoom state unless the axes contral is empty
            if ~isempty(app.ax.Children)
                XLim=app.ax.XLim;
                YLim=app.ax.YLim;
                bln_ax_empty=false;
            else
                bln_ax_empty=true;
            end
            % inherit axes limit
            if ~bln_ax_empty
                app.ax.XLim=XLim;
                app.ax.YLim=YLim;
                [X,Y]=size(img);
                app.ax.InteractionOptions.RestoredXLimits=[1,Y];
                app.ax.InteractionOptions.RestoredYLimits=[1,X];
                % bln_ax_empty=false;
            else
                [X,Y]=size(img);
                app.ax.XLim=[1,Y];
                app.ax.YLim=[1,X];
            end

            % if peak finding is required plot the results on the image

            if app.chkPkfnd.Value
                % pos=app.poslst(app.poslst(:,3)==app.spnFrame.Value,:);
                % if ~isempty(pos)
                % hold(app.ax,'on');

                % app.h_cnd=plot(app.ax,pos(:,1),pos(:,2),'x',...
                %     'LineStyle','none','MarkerSize',10,'LineWidth',1,'Color','r');
                pk=pkfnd(img,app.spnMinBrightness.Value,app.edtPkfndDia.Value);
                if app.edtPkfndDia.Value/2==floor(app.edtPkfndDia.Value/2)
                    cnt=cntrd(img,pk,app.edtPkfndDia.Value+3);
                else
                    cnt=cntrd(img,pk,app.edtPkfndDia.Value+2);
                end

                if ~isempty(pk) & ~isempty(cnt)
                    cnt(isnan(cnt(:,1)),:)=[];
                    hold(app.ax,'on');
                    app.h_pk=plot(app.ax,pk(:,1),pk(:,2),'+',...
                        'LineStyle','none','MarkerSize',10,'LineWidth',1,'Color','g');
                    app.h_cnd=plot(app.ax,cnt(:,1),cnt(:,2),'x',...
                        'LineStyle','none','MarkerSize',10,'LineWidth',1,'Color','r');
                    hold(app.ax,'off');
                end
            else
                delete(findobj(app.ax,'type','Line'))
                app.h_pk=[];
                app.h_cnd=[];
            end

            % if there is track data plot on the image
            if ~isempty(app.tr)
                % clear the position marks
                delete(findobj(app.ax,'type','Line'))

                app.h_pk=[];
                app.h_cnd=[];

                hold(app.ax,'on');
                tr=app.tr;
                ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                tr_temp=arrayfun(@(x,y) tr(x:y,:),ind(:,1),ind(:,2),'UniformOutput',0);

                if app.chkDriftCanceling.Value & ~isempty(app.drift_profile)
                    drift=app.drift_profile;
                    for i=1:length(tr_temp)
                        t=tr_temp{i};
                        start_step=t(1,3);
                        for j=2:size(t,1)

                            step_id=t(j,3);
                            rel_drift=drift(step_id-min(drift(:,3)),1:2)-drift(start_step-min(drift(:,3)),1:2);
                            t(j,1:2)=t(j,1:2)-rel_drift;
                        end

                        tr_temp{i}=t;
                    end
                end

                % plot the whole trajectory
                cellfun(@(x)plot(app.ax,x(:,1),x(:,2),'b.-'),tr_temp,'UniformOutput',0);
                % plot the position in the current frame of the trajectory
                plot(app.ax,tr(tr(:,3)==app.sldFrame.Value,1),tr(tr(:,3)==app.sldFrame.Value,2),...
                    'x',...
                    'LineStyle','none','MarkerSize',10,'LineWidth',1,'Color','magenta');
                % plot(app.ax,app.poslst(app.poslst(:,3)==app.sldFrame.Value,1),app.poslst(app.poslst(:,3)==app.sldFrame.Value,2),'bx');
                hold(app.ax,'off');
            end


            % Update other UI controls
            if floor(double(max(img(:))))<=0
                app.sldMinBrightness.Limits=[0,1];
                % app.spnMinBrightness.Limits=app.sldMinBrightness.Limits;
            else
                app.sldMinBrightness.Limits=[0,floor(double(max(img(:))))];
                % app.spnMinBrightness.Limits=app.sldMinBrightness.Limits;
            end
            % bln_ax_empty=false;

            % set the new image as the current image
            app.current_img=img;

            figure(app.UIPanel)

        end


        function results = track(app,xyzs,maxdisp,param)

            %;
            % ; see http://glinda.lrsm.upenn.edu/~weeks/idl
            % ;   for more information
            % ;
            % ;+
            % ; NAME:
            % ; track
            % ; PURPOSE:
            % ; Constructs n-dimensional trajectories from a scrambled list of
            % ; particle coordinates determined at discrete times (e.g. in
            % ; consecutive video frames).
            % ; CATEGORY:
            % ; Image Processing
            % ; CALLING SEQUENCE:
            % ; result = track( positionlist, maxdisp, param )
            % ;  set all keywords in the space below
            % ; INPUTS:
            % ; positionlist: an array listing the scrambled coordinates and data
            % ;     of the different particles at different times, such that:
            % ;  positionlist(0:d-1,*): contains the d coordinates and
            % ;     data for all the particles, at the different times. must be positve
            % ;  positionlist(d,*): contains the time t that the position
            % ;     was determined, must be integers (e.g. frame number.  These values must
            % ;               be monotonically increasing and uniformly gridded in time.
            % ; maxdisp: an estimate of the maximum distance that a particle
            % ;     would move in a single time interval.(see Restrictions)
            %  OPTIONAL INPUT:
            %   param:  a structure containing a few tracking parameters that are
            %       needed for many applications.  If param is not included in the
            %       function call, then default values are used.  If you set one value
            %       make sure you set them all:
            % ;         param.mem: this is the number of time steps that a particle can be
            % ;             'lost' and then recovered again.  If the particle reappears
            % ;             after this number of frames has elapsed, it will be
            % ;             tracked as a new particle. The default setting is zero.
            % ;             this is useful if particles occasionally 'drop out' of
            % ;             the data.
            % ;         param.dim: if the user would like to unscramble non-coordinate data
            % ;             for the particles (e.g. apparent radius of gyration for
            % ;             the particle images), then positionlist should
            % ;             contain the position data in positionlist(0:param.dim-1,*)
            % ;             and the extra data in positionlist(param.dim:d-1,*). It is then
            % ;             necessary to set dim equal to the dimensionality of the
            % ;             coordinate data to so that the track knows to ignore the
            % ;             non-coordinate data in the construction of the
            % ;             trajectories. The default value is two.
            % ;         param.good: set this keyword to eliminate all trajectories with
            % ;             fewer than param.good valid positions.  This is useful
            % ;             for eliminating very short, mostly 'lost' trajectories
            % ;             due to blinking 'noise' particles in the data stream.
            %;          param.quiet: set this keyword to 1 if you don't want any text
            % ; OUTPUTS:
            % ; result:  a list containing the original data rows sorted
            % ;     into a series of trajectories.  To the original input
            % ;     data structure there is appended an additional column
            % ;     containing a unique 'id number' for each identified
            % ;     particle trajectory.  The result array is sorted so
            % ;     rows with corresponding id numbers are in contiguous
            % ;     blocks, with the time variable a monotonically
            % ;     increasing function inside each block.  For example:
            % ;
            % ;     For the input data structure (positionlist):
            % ;         (x)      (y)      (t)
            % ;     pos = 3.60000      5.00000      0.00000
            % ;           15.1000      22.6000      0.00000
            % ;           4.10000      5.50000      1.00000
            % ;           15.9000      20.7000      2.00000
            % ;           6.20000      4.30000      2.00000
            % ;
            % ;     IDL> res = track(pos,5,mem=2)
            % ;
            % ;     track will return the result 'res'
            % ;         (x)      (y)      (t)          (id)
            % ;     res = 3.60000      5.00000      0.00000      0.00000
            % ;           4.10000      5.50000      1.00000      0.00000
            % ;           6.20000      4.30000      2.00000      0.00000
            % ;           15.1000      22.6000      0.00000      1.00000
            % ;           15.9000      20.7000      2.00000      1.00000
            % ;
            % ;     NB: for t=1 in the example above, one particle temporarily
            % ;     vanished.  As a result, the trajectory id=1 has one time
            % ;     missing, i.e. particle loss can cause time gaps to occur
            % ;     in the corresponding trajectory list. In contrast:
            % ;
            % ;     IDL> res = track(pos,5)
            % ;
            % ;     track will return the result 'res'
            % ;         (x)      (y)      (t)          (id)
            % ;     res = 15.1000      22.6000      0.00000      0.00000
            % ;                   3.60000      5.00000      0.00000      1.00000
            % ;               4.10000      5.50000      1.00000      1.00000
            % ;               6.20000      4.30000      2.00000      1.00000
            % ;               15.9000      20.7000      2.00000      2.00000
            % ;
            % ;     where the reappeared 'particle' will be labelled as new
            % ;     rather than as a continuation of an old particle since
            % ;     mem=0.  It is up to the user to decide what setting of
            % ;     'mem' will yeild the highest fidelity .
            % ;
            % ; SIDE EFFECTS:
            % ; Produces informational messages.  Can be memory intensive for
            % ; extremely large data sets.
            % ; RESTRICTIONS:
            % ; maxdisp should be set to a value somewhat less than the mean
            % ; spacing between the particles. As maxdisp approaches the mean
            % ; spacing the runtime will increase significantly. The function
            % ; will produce an error message: "Excessive Combinatorics!" if
            % ; the run time would be too long, and the user should respond
            % ; by re-executing the function with a smaller value of maxdisp.
            % ; Obviously, if the particles being tracked are frequently moving
            % ; as much as their mean separation in a single time step, this
            % ; function will not return acceptable trajectories.
            % ; PROCEDURE:
            % ; Given the positions for n particles at time t(i), and m possible
            % ; new positions at time t(i+1), this function considers all possible
            % ; identifications of the n old positions with the m new positions,
            % ; and chooses that identification which results in the minimal total
            % ; squared displacement. Those identifications which don't associate
            % ; a new position within maxdisp of an old position ( particle loss )
            % ; penalize the total squared displacement by maxdisp^2. For non-
            % ; interacting Brownian particles with the same diffusivity, this
            % ; algorithm will produce the most probable set of identifications
            % ; ( provided maxdisp >> RMS displacement between frames ).
            % ; In practice it works reasonably well for systems with oscillatory,
            % ; ballistic, correlated and random hopping motion, so long as single
            % ; time step displacements are reasonably small.  NB: multidimensional
            % ; functionality is intended to facilitate tracking when additional
            % ; information regarding target identity is available (e.g. size or
            % ; color).  At present, this information should be rescaled by the
            % ; user to have a comparable or smaller (measurement) variance than
            % ; the spatial displacements.
            % ;
            % ; MODIFICATION HISTORY:
            % ;  2/93 Written by John C. Crocker, University of Chicago (JFI).
            % ;  7/93 JCC fixed bug causing particle loss and improved performance
            % ;     for large numbers of (>100) particles.
            % ; 11/93 JCC improved speed and memory performance for large
            % ;     numbers of (>1000) particles (added subnetwork code).
            % ;  3/94 JCC optimized run time for trivial bonds and d<7. (Added
            % ;     d-dimensional raster metric code.)
            % ;  8/94 JCC added functionality to unscramble non-position data
            % ;     along with position data.
            % ;  9/94 JCC rewrote subnetwork code and wrote new, more efficient
            % ;     permutation code.
            % ;  5/95 JCC debugged subnetwork and excessive combinatorics code.
            % ; 12/95 JCC added memory keyword, and enabled the tracking of
            % ;     newly appeared particles.
            % ;  3/96 JCC made inipos a keyword, and disabled the adding of 'new'
            % ;     particles when inipos was set.
            % ;  3/97 JCC added 'add' keyword, since Chicago users didn't like
            % ;     having particle addition be the default.
            % ;  9/97 JCC added 'goodenough' keyword to improve memory efficiency
            % ;     when using the 'add' keyword and to filter out bad tracks.
            % ;       10/97 JCC streamlined data structure to speed runtime for >200
            % ;               timesteps.  Changed 'quiet' keyword to 'verbose'. Made
            % ;               time labelling more flexible (uniform and sorted is ok).
            % ;  9/98 JCC switched trajectory data structure to a 'list' form,
            % ;     resolving memory issue for large, noisy datasets.
            % ;  2/99 JCC added Eric Weeks's 'uberize' code to post-facto
            % ;     rationalize the particle id numbers, removed 'add' keyword.
            % ;  1/05 Transmuted to MATLAB by D. Blair
            % ;  5/05  ERD Added the param structure to simplify calling.
            %    6/05  ERD Added quiet to param structure
            %    7/05  DLB Fixed slight bug in trivial bond code
            %    3/07  DLB Fixed bug with max disp pointed out by Helene Delanoe-Ayari
            %    3/24 Andrew Sun transplanted into his GUI app and modified
            %    some inapproprate languages in the output messages.
            %
            % ; This code 'track.pro' is copyright 1999, by John C. Crocker.
            % ; It should be considered 'freeware'- and may be distributed freely
            % ; (outside of the military-industrial complex) in its original form
            % ; when properly attributed.
            % ;
            % ;-

            dd = length(xyzs(1,:));

            %use default parameters if none given
            if nargin==2
                %default values
                memory_b=0; % if mem is not needed set to zero
                goodenough = 0;  % if goodenough is not wanted set to zero
                dim = dd - 1;
                quiet=0;
            else
                memory_b    =   param.mem;
                goodenough  =   param.good;
                dim         =   param.dim;
                quiet       =   param.quiet;
            end

            app.txtMessage.Value=[""];

            % checking the input time vector
            t = xyzs(:,dd);
            st = circshift(t,1);
            st = t(2:end) - st(2:end);
            if  sum(st(find(st < 0))) ~= 0
                disp('The time vectors is not in order')
                app.txtMessage.Value=[app.txtMesage.Value;...
                    "The time vectors is not in order"];
                results=[];
                return
            end
            info = 1;

            w = find(st > 0);
            z = length(w);
            z = z +1;
            if isempty(w)
                disp('All positions are at the same time... go back!')
                app.txtMessage.Value=[app.txtMessage.Value;...
                    "All positions are at the same time... go back!"];
                results=[];
                return
            end

            % partitioning the data with unique times

            %res = unq(t);
            % implanting unq directly
            indices = find(t ~= circshift(t,-1));
            count = length(indices);
            if count > 0
                res = indices;
            else
                res = length(t) -1;
            end
            %%%%%%%%%%%%%%%%%%%%%%%

            res = [1,res',length(t)];
            ngood = res(2) - res(1) + 1;
            eyes = 1:ngood;
            pos = xyzs(eyes,1:dim);
            istart = 2;
            n = ngood;

            zspan = 50;
            if n > 200
                zspan = 20;
            end
            if n > 500
                zspan = 10;
            end
            resx = zeros(zspan,n) - 1;

            bigresx = zeros(z,n) - 1;
            mem = zeros(n,1);
            %  whos resx
            %  whos bigresx
            uniqid = 1:n;
            maxid = n;
            olist = [0.,0.];

            if goodenough > 0
                dumphash = zeros(n,1);
                nvalid = ones(n,1);
            end

            %  whos eyes;
            resx(1,:) = eyes;
            % setting up constants
            maxdisq = maxdisp^2;

            % John calls this the setup for "fancy code" ???
            notnsqrd = (sqrt(n*ngood) > 200) & (dim < 7);
            notnsqrd = notnsqrd(1);

            if notnsqrd
                %;   construct the vertices of a 3x3x3... d-dimensional hypercube

                cube = zeros(3^dim,dim);


                for d=0:dim-1,
                    numb = 0;
                    for j=0:(3^d):(3^dim)-1,
                        cube(j+1:j+(3^(d)),d+1) = numb;
                        numb = mod(numb+1,3);
                    end
                end

                %   calculate a blocksize which may be greater than maxdisp, but which
                %   keeps nblocks reasonably small.

                volume = 1;
                for d = 0:dim-1
                    minn = min(xyzs(w,d+1));
                    maxx = max(xyzs(w,d+1));
                    volume = volume * (maxx-minn);
                end
                volume;
                blocksize = max( [maxdisp,((volume)/(20*ngood))^(1.0/dim)] );
            end
            %   Start the main loop over the frames.
            for i=istart:z
                ispan = mod(i-1,zspan)+1;
                %disp(ispan)
                % get new particle positions
                m = res(i+1) - res(i);
                res(i);
                eyes = 1:m;
                eyes = eyes + res(i);

                if m > 0

                    xyi = xyzs(eyes,1:dim);
                    found = zeros(m,1);

                    % THE TRIVIAL BOND CODE BEGINS

                    if notnsqrd
                        %Use the raster metric code to do trivial bonds

                        % construct "s", a one dimensional parameterization of the space
                        % which consists of the d-dimensional raster scan of the volume.)

                        abi = fix(xyi./blocksize);
                        abpos = fix(pos./blocksize);
                        si = zeros(m,1);
                        spos = zeros(n,1);
                        dimm = zeros(dim,1);
                        coff = 1.;

                        for j=1:dim
                            minn = min([abi(:,j);abpos(:,j)]);
                            maxx = max([abi(:,j);abpos(:,j)]);
                            abi(:,j) = abi(:,j) - minn;
                            abpos(:,j) = abpos(:,j) - minn;
                            dimm(j) = maxx-minn + 1;
                            si = si + abi(:,j).*coff;
                            spos = spos + abpos(:,j).*coff;
                            coff = dimm(j).*coff;
                        end
                        nblocks = coff;
                        % trim down (intersect) the hypercube if its too big to fit in the
                        % particle volume. (i.e. if dimm(j) lt 3)

                        cub = cube;
                        deg = find( dimm < 3);
                        if ~isempty(deg)
                            for j = 0:length(deg)-1
                                cub = cub(find(cub(:,deg(j+1)) < dimm(deg(j+1))),:);
                            end
                        end

                        % calculate the "s" coordinates of hypercube (with a corner @ the origin)
                        scube = zeros(length(cub(:,1)),1);
                        coff = 1;
                        for j=1:dim
                            scube = scube + cub(:,j).*coff;
                            coff = coff*dimm(j);
                        end

                        % shift the hypercube "s" coordinates to be centered around the origin

                        coff = 1;
                        for j=1:dim
                            if dimm(j) > 3
                                scube = scube - coff;
                            end
                            coff = dimm(j).* coff;
                        end
                        scube = mod((scube + nblocks),nblocks);
                        % get the sorting for the particles by their "s" positions.
                        [ed,isort] = sort(si);

                        % make a hash table which will allow us to know which new particles
                        % are at a given si.
                        strt = zeros(nblocks,1) -1;
                        fnsh = zeros(nblocks,1);
                        h = find(si == 0);
                        lh = length(h);
                        if lh > 0

                            si(h) = 1;
                        end

                        for j=1:m
                            if strt(si(isort(j))) == -1
                                strt(si(isort(j))) = j;
                                fnsh(si(isort(j))) = j;
                            else
                                fnsh(si(isort(j))) = j;
                            end
                        end
                        if lh > 0
                            si(h) = 0;
                        end
                        coltot = zeros(m,1);
                        rowtot = zeros(n,1);
                        which1 = zeros(n,1);
                        for j=1:n


                            map = fix(-1);

                            scub_spos = scube + spos(j);
                            s = mod(scub_spos,nblocks);
                            whzero = find(s == 0 );
                            if ~isempty(whzero)
                                nfk = find(s ~=0);
                                s = s(nfk);
                            end

                            w = find(strt(s) ~= -1);

                            ngood = length(w);
                            ltmax=0;
                            if ngood ~= 0

                                s = s(w);
                                for k=1:ngood
                                    map = [map;isort( strt(s(k)):fnsh(s(k)))];
                                end
                                map = map(2:end);
                                %                     if length(map) == 2
                                %                         if (map(1) - map(2)) == 0
                                %                             map = unique(map);
                                %                          end
                                %                     end
                                %   map = map(umap);
                                %end
                                % find those trival bonds
                                distq = zeros(length(map),1);
                                for d=1:dim
                                    distq = distq + (xyi(map,d) - pos(j,d)).^2;
                                end
                                ltmax = distq < maxdisq;

                                rowtot(j) = sum(ltmax);

                                if rowtot(j) >= 1
                                    w = find(ltmax == 1);
                                    coltot( map(w) ) = coltot( map(w)) +1;
                                    which1(j) = map( w(1) );
                                end
                            end

                        end


                        ntrk = fix(n - sum(rowtot == 0));

                        w = find( rowtot == 1);
                        ngood = length(w);


                        if ngood ~= 0
                            ww = find(coltot( which1(w) ) == 1);
                            ngood = length(ww);
                            if ngood ~= 0
                                %disp(size(w(ww)))
                                resx(ispan,w(ww)) = eyes( which1(w(ww)));
                                found( which1( w(ww))) = 1;
                                rowtot( w(ww)) = 0;
                                coltot( which1(w(ww))) = 0;
                            end
                        end

                        labely = find( rowtot > 0);
                        ngood = length(labely);
                        if ngood ~= 0
                            labelx = find( coltot > 0);

                            nontrivial = 1;
                        else
                            nontrivial = 0;
                        end

                    else

                        %   or: Use simple N^2 time routine to calculate trivial bonds

                        % let's try a nice, loopless way!
                        % don't bother tracking perm. lost guys.
                        wh = find( pos(:,1) >= 0);
                        ntrack = length(wh);
                        if ntrack == 0
                            % 'There are no valid particles to track idiot!'
                            app.txtMessage.Value=[app.txtMessage.Value;...
                                "There are no valid particles to track."];

                            break
                        end
                        xmat = zeros(ntrack,m);
                        count = 0;
                        for kk=1:ntrack
                            for ll=1:m
                                xmat(kk,ll) = count;
                                count = count+1;
                            end
                        end
                        count = 0;
                        for kk=1:m
                            for ll=1:ntrack
                                ymat(kk,ll) = count;
                                count = count+1;
                            end
                        end

                        xmat = (mod(xmat,m) + 1);
                        ymat = (mod(ymat,ntrack) +1)';
                        [lenxn,lenxm] = size(xmat);
                        %            whos ymat
                        %            whos xmat
                        %            disp(m)

                        for d=1:dim
                            x = xyi(:,d);
                            y = pos(wh,d);
                            xm = x(xmat);
                            ym = y(ymat(1:lenxn,1:lenxm));
                            if size(xm) ~= size(ym)
                                xm = xm';
                            end

                            if d == 1
                                dq = (xm -ym).^2;
                                %dq = (x(xmat)-y(ymat(1:lenxn,1:lenxm))).^2;
                            else
                                dq = dq + (xm-ym).^2;
                                %dq = dq + (x(xmat)-y(ymat(1:lenxn,1:lenxm)) ).^2;
                            end
                        end

                        ltmax = dq < maxdisq;

                        % figure out which trivial bonds go with which

                        rowtot = zeros(n,1);
                        rowtot(wh) = sum(ltmax,2);


                        if ntrack > 1
                            coltot = sum(ltmax,1);
                        else
                            coltot = ltmax;
                        end
                        which1 = zeros(n,1);
                        for j=1:ntrack
                            [mx, w] = max(ltmax(j,:));
                            which1(wh(j)) = w;
                        end

                        ntrk = fix( n - sum(rowtot == 0));
                        w= find( rowtot == 1) ;
                        ngood = length(w);
                        if ngood ~= 0
                            ww = find(coltot(which1(w)) == 1);
                            ngood = length(ww);
                            if ngood ~= 0
                                resx( ispan, w(ww) ) = eyes( which1( w(ww)));
                                found(which1( w(ww))) = 1;
                                rowtot(w(ww)) = 0;
                                coltot(which1(w(ww))) = 0;
                            end
                        end

                        labely = find( rowtot > 0);
                        ngood = length(labely);

                        if ngood ~= 0
                            labelx = find( coltot > 0);
                            nontrivial = 1;
                        else
                            nontrivial = 0;
                        end
                    end

                    %THE TRIVIAL BOND CODE ENDS

                    if nontrivial

                        xdim = length(labelx);
                        ydim = length(labely);

                        %  make a list of the non-trivial bonds

                        bonds = zeros(1,2);
                        bondlen = 0;

                        for j=1:ydim
                            distq = zeros(xdim,1);

                            for d=1:dim
                                %distq
                                distq = distq + (xyi(labelx,d) - pos(labely(j),d)).^2;
                                %distq
                            end

                            w= find(distq <  maxdisq)' - 1;
                            ngood = length(w);
                            newb = [w;(zeros(1,ngood)+j)];


                            bonds = [bonds;newb'];

                            bondlen = [ bondlen;distq( w + 1) ];

                        end
                        bonds = bonds(2:end,:);

                        bondlen = bondlen(2:end);
                        numbonds = length(bonds(:,1));
                        mbonds = bonds;
                        max([xdim,ydim]);


                        if max([xdim,ydim]) < 4
                            nclust = 1;
                            maxsz = 0;
                            mxsz = xdim;
                            mysz = ydim;
                            bmap = zeros(length(bonds(:,1)+1),1) - 1;

                        else


                            %   THE SUBNETWORK CODE BEGINS
                            lista = zeros(numbonds,1);
                            listb = zeros(numbonds,1);
                            nclust = 0;
                            maxsz = 0;
                            thru = xdim;

                            while thru ~= 0
                                %  the following code extracts connected
                                %   sub-networks of the non-trivial
                                %   bonds.  NB: lista/b can have redundant entries due to
                                %   multiple-connected subnetworks


                                w = find(bonds(:,2) >= 0);
                                %                 size(w)

                                lista(1) = bonds(w(1),2);
                                listb(1) = bonds(w(1),1);
                                bonds(w(1),:) = -(nclust+1);
                                bonds;
                                adda = 1;
                                addb = 1;
                                donea = 0;
                                doneb = 0;
                                if (donea ~= adda) | (doneb ~= addb)
                                    true = 0;
                                else
                                    true = 1;
                                end

                                while ~true

                                    if (donea ~= adda)
                                        w = find(bonds(:,2) == lista(donea+1));
                                        ngood = length(w);
                                        if ngood ~= 0
                                            listb(addb+1:addb+ngood,1) = bonds(w,1);
                                            bonds(w,:) = -(nclust+1);
                                            addb = addb+ngood;
                                        end
                                        donea = donea+1;
                                    end
                                    if (doneb ~= addb)
                                        w = find(bonds(:,1) == listb(doneb+1));
                                        ngood = length(w);
                                        if ngood ~= 0
                                            lista(adda+1:adda+ngood,1) = bonds(w,2);
                                            bonds(w,:) = -(nclust+1);
                                            adda = adda+ngood;
                                        end
                                        doneb = doneb+1;
                                    end
                                    if (donea ~= adda) | (doneb ~= addb)
                                        true = 0;
                                    else
                                        true = 1;
                                    end
                                end

                                [pp,pqx] = sort(listb(1:doneb));
                                %unx =  unq(listb(1:doneb),pqx);
                                %implanting unq directly
                                arr = listb(1:doneb);
                                q = arr(pqx);
                                indices = find(q ~= circshift(q,-1));
                                count = length(indices);
                                if count > 0
                                    unx = pqx(indices);
                                else
                                    unx = length(q) -1;
                                end
                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                                xsz = length(unx);
                                [pp,pqy] = sort(lista(1:donea));
                                %uny =  unq(lista(1:donea),pqy);
                                %implanting unq directly
                                arr = lista(1:donea);
                                q = arr(pqy);
                                indices = find(q ~= circshift(q,-1));
                                count = length(indices);
                                if count > 0
                                    uny = pqy(indices);
                                else
                                    uny = length(q) -1;
                                end
                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                                ysz = length(uny);
                                if xsz*ysz > maxsz
                                    maxsz = xsz*ysz;
                                    mxsz = xsz;
                                    mysz = ysz;
                                end


                                thru = thru -xsz;
                                nclust = nclust + 1;
                            end
                            bmap = bonds(:,2);
                        end
                        % THE SUBNETWORK CODE ENDS
                        % put verbose in for Jaci

                        %   THE PERMUTATION CODE BEGINS

                        for nc =1:nclust
                            w = find( bmap == -1*(nc));

                            nbonds = length(w);
                            bonds = mbonds(w,:);
                            lensq = bondlen(w);
                            [~,st] = sort( bonds(:,1));
                            %un = unq(bonds(:,1),st);
                            %implanting unq directly
                            arr = bonds(:,1);
                            q = arr(st);
                            indices = find(q ~= circshift(q,-1));
                            count = length(indices);
                            if count > 0
                                un = st(indices);
                            else
                                un = length(q) -1;
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                            uold = bonds(un,1);

                            nold = length(uold);

                            %un = unq(bonds(:,2));

                            %implanting unq directly
                            indices = find(bonds(:,2) ~= circshift(bonds(:,2),-1));
                            count = length(indices);
                            if count > 0
                                un = indices;
                            else
                                un = length(bonds(:,2)) -1;
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                            unew = bonds(un,2);
                            nnew = length(unew);

                            if nnew > 5
                                rnsteps = 1;
                                for ii =1:nnew
                                    rnsteps = rnsteps * length( find(bonds(:,2) == ...
                                        unew(ii)));
                                    if rnsteps > 5.e+5
                                        disp('Warning: difficult combinatorics encountered.')
                                        app.txtMessage.Value=[app.txtMessage.Value;...
                                            "Warning: difficult combinatorics encountered."];
                                    end
                                    if rnsteps > 2.e+6
                                        disp(['Excessive Combinitorics you FOOL LOOK WHAT YOU HAVE' ...
                                            ' DONE TO ME!!!'])
                                        app.txtMessage.Value=[app.txtMessage.Value;...
                                            "Excessive combinitorics!"];
                                        results=[];
                                        return
                                    end
                                end
                            end
                            st = zeros(nnew,1);
                            fi = zeros(nnew,1);
                            h = zeros(nbonds,1);
                            ok = ones(nold,1);
                            nlost = (nnew - nold) > 0;


                            for ii=1:nold
                                h(find(bonds(:,1) == uold(ii))) = ii;
                            end
                            st(1) = 1 ;
                            fi(nnew) = nbonds; % check this later
                            if nnew > 1
                                sb = bonds(:,2);
                                sbr = circshift(sb,1);
                                sbl = circshift(sb,-1);
                                st(2:end) = find( sb(2:end) ~= sbr(2:end)) + 1;
                                fi(1:nnew-1) = find( sb(1:nbonds-1) ~= sbl(1:nbonds-1));
                            end
                            %                if i-1 == 13
                            %                    hi
                            %                end
                            checkflag = 0;
                            while checkflag ~= 2

                                pt = st -1;
                                lost = zeros(nnew,1);
                                who = 0;
                                losttot = 0;
                                mndisq = nnew*maxdisq;


                                while who ~= -1

                                    if pt(who+1) ~= fi(who+1)


                                        w = find( ok( h( pt( who+1 )+1:fi( who+1 ) ) ) ); % check this -1
                                        ngood = length(w);
                                        if ngood > 0
                                            if pt(who+1) ~= st(who+1)-1
                                                ok(h(pt(who+1))) = 1;
                                            end
                                            pt(who+1) = pt(who+1) + w(1);
                                            ok(h(pt(who+1))) = 0;
                                            if who == nnew -1
                                                ww = find( lost == 0);
                                                dsq = sum(lensq(pt(ww))) + losttot*maxdisq;

                                                if dsq < mndisq
                                                    minbonds = pt(ww);
                                                    mndisq = dsq;
                                                end
                                            else
                                                who = who+1;
                                            end
                                        else
                                            if ~lost(who+1) & (losttot ~= nlost)
                                                lost(who+1) = 1;
                                                losttot = losttot + 1;
                                                if pt(who+1) ~= st(who+1) -1;
                                                    ok(h(pt(who+1))) = 1;
                                                end
                                                if who == nnew-1
                                                    ww = find( lost == 0);
                                                    dsq = sum(lensq(pt(ww))) + losttot*maxdisq;
                                                    if dsq < mndisq
                                                        minbonds = pt(ww);
                                                        mndisq = dsq;
                                                    end
                                                else
                                                    who = who + 1;
                                                end

                                            else
                                                if pt(who+1) ~= (st(who+1) -1)
                                                    ok(h(pt(who+1))) = 1;
                                                end
                                                pt(who+1) = st(who+1) -1;
                                                if lost(who+1)
                                                    lost(who+1) = 0;
                                                    losttot = losttot -1;
                                                end
                                                who = who -1;
                                            end
                                        end
                                    else
                                        if ~lost(who+1) & (losttot ~= nlost)
                                            lost(who+1) = 1;
                                            losttot = losttot + 1;
                                            if pt(who+1) ~= st(who+1)-1
                                                ok(h(pt(who+1))) = 1;
                                            end
                                            if who == nnew -1
                                                ww = find( lost == 0);
                                                dsq = sum(lensq(pt(ww))) + losttot*maxdisq;

                                                if dsq < mndisq
                                                    minbonds = pt(ww);
                                                    mndisq = dsq;
                                                end
                                            else
                                                who = who + 1;
                                            end
                                        else
                                            if pt(who+1) ~= st(who+1) -1
                                                ok(h(pt(who+1))) = 1;
                                            end
                                            pt(who+1) = st(who+1) -1;
                                            if lost(who+1)
                                                lost(who+1) = 0;
                                                losttot = losttot -1;
                                            end
                                            who = who -1;
                                        end
                                    end
                                end

                                checkflag = checkflag + 1;
                                if checkflag == 1
                                    plost = min([fix(mndisq/maxdisq) , (nnew -1)]);
                                    if plost > nlost
                                        nlost = plost;
                                    else
                                        checkflag = 2;
                                    end
                                end

                            end
                            %   update resx using the minimum bond configuration

                            resx(ispan,labely(bonds(minbonds,2))) = eyes(labelx(bonds(minbonds,1)+1));
                            found(labelx(bonds(minbonds,1)+1)) = 1;

                        end

                        %   THE PERMUTATION CODE ENDS
                    end

                    w = find(resx(ispan,:) >= 0);
                    nww = length(w);

                    if nww > 0
                        pos(w,:) = xyzs( resx(ispan,w) , 1:dim);
                        if goodenough > 0
                            nvalid(w) = nvalid(w) + 1;
                        end
                    end  %go back and add goodenough keyword thing
                    newguys = find(found == 0);

                    nnew = length(newguys);

                    if (nnew > 0) % & another keyword to workout inipos
                        newarr = zeros(zspan,nnew) -1;
                        resx = [resx,newarr];

                        resx(ispan,n+1:end) = eyes(newguys);
                        pos = [[pos];[xyzs(eyes(newguys),1:dim)]];
                        nmem = zeros(nnew,1);
                        mem = [mem;nmem];
                        nun = 1:nnew;
                        uniqid = [uniqid,((nun) + maxid)];
                        maxid = maxid + nnew;
                        if goodenough > 0
                            dumphash = [dumphash;zeros(1,nnew)'];
                            nvalid = [nvalid;zeros(1,nnew)'+1];
                        end
                        % put in goodenough
                        n = n + nnew;

                    end

                else
                    ' Warning- No positions found for t='
                    app.txtMessage.Value=[app.txtMessage.Value;...
                        " Warning- No positions found for t="]


                end
                w = find( resx(ispan,:) ~= -1);
                nok = length(w);
                if nok ~= 0
                    mem(w) =0;
                end

                mem = mem + (resx(ispan,:)' == -1);
                wlost = find(mem == memory_b+1);
                nlost =length(wlost);

                if nlost > 0
                    pos(wlost,:) = -maxdisp;
                    if goodenough > 0
                        wdump = find(nvalid(wlost) < goodenough);
                        ndump = length(wdump);
                        if ndump > 0
                            dumphash(wlost(wdump)) = 1;
                        end
                    end
                    % put in goodenough keyword stuff if
                end
                if (ispan == zspan) | (i == z)
                    nold = length(bigresx(1,:));
                    nnew = n-nold;
                    if nnew > 0
                        newarr = zeros(z,nnew) -1;
                        bigresx = [bigresx,newarr];
                    end
                    if goodenough > 0
                        if (sum(dumphash)) > 0
                            wkeep = find(dumphash == 0);
                            nkeep = length(wkeep);
                            resx = resx(:,wkeep);
                            bigresx = bigresx(:,wkeep);
                            pos = pos(wkeep,:);
                            mem = mem(wkeep);
                            uniqid = uniqid(wkeep);
                            nvalid = nvalid(wkeep);
                            n = nkeep;
                            dumphash = zeros(nkeep,1);
                        end
                    end

                    % again goodenough keyword
                    if quiet~=1
                        disp(strcat(num2str(i), ' of ' ,num2str(z), ' done.  Tracking  ',num2str(ntrk),' particles  ', num2str(n),' tracks total'));
                        app.txtMessage.Value=[app.txtMessage.Value;...
                            join([num2str(i), " of " ,num2str(z), " done.  Tracking  ",num2str(ntrk)," particles  ", num2str(n)," tracks total"])];
                    end
                    bigresx(i-(ispan)+1:i,:) = resx(1:ispan,:);
                    resx = zeros(zspan,n) - 1;


                    wpull = find(pos(:,1) == -maxdisp);
                    npull = length(wpull);

                    if npull > 0
                        lillist = zeros(1,2);
                        for ipull=1:npull
                            wpull2 = find(bigresx(:,wpull(ipull)) ~= -1);
                            npull2 = length(wpull2);
                            thing = [bigresx(wpull2,wpull(ipull)),zeros(npull2,1)+uniqid(wpull(ipull))];
                            lillist = [lillist;thing];

                        end
                        olist = [[olist];[lillist(2:end,:)]];

                    end


                    wkeep = find(pos(:,1) >= 0);
                    nkeep = length(wkeep);
                    if nkeep == 0
                        'Were going to crash now, no particles....'
                        app.txtMessage.Value=[app.txtMessage.Value;...
                            "Were going to crash now, no particles..."];
                    end
                    resx = resx(:,wkeep);
                    bigresx = bigresx(:,wkeep);
                    pos = pos(wkeep,:);
                    mem = mem(wkeep);
                    uniqid = uniqid(wkeep);
                    n = nkeep;
                    dumphash = zeros(nkeep,1);
                    if goodenough > 0
                        nvalid = nvalid(wkeep);
                    end
                end

            end

            if goodenough > 0
                nvalid = sum(bigresx >= 0 ,1);
                wkeep = find(nvalid >= goodenough);
                nkeep = length(wkeep);
                if nkeep == 0
                    % for i=1:10
                    %     disp('You are not going any further, check your params and data')
                    % end
                    % disp('the code broke at line 1045')
                    app.txtMessage.Value=[app.txtMessage.Value;...
                        "No valid track per your parameters!"];
                    results=[];
                    return
                end
                if nkeep < n
                    bigresx = bigresx(:,wkeep);
                    n = nkeep;
                    uniqid = uniqid(wkeep);
                    pos = pos(wkeep,:);
                end
            end


            wpull = find( pos(:,1) ~= -2*maxdisp);
            npull = length(wpull);
            if npull > 0
                lillist = zeros(1,2);
                for ipull=1:npull
                    wpull2 = find(bigresx(:,wpull(ipull)) ~= -1);
                    npull2 = length(wpull2);
                    thing = [bigresx(wpull2,wpull(ipull)),zeros(npull2,1)+uniqid(wpull(ipull))];
                    lillist = [lillist;thing];
                end
                olist = [olist;lillist(2:end,:)];
            end

            olist = olist(2:end,:);
            %bigresx = 0;
            %resx = 0;

            nolist = length(olist(:,1));
            res = zeros(nolist,dd+1);
            for j=1:dd
                res(:,j) = xyzs(olist(:,1),j);
            end
            res(:,dd+1) = olist(:,2);

            % this is uberize included for simplicity of a single monolithic code

            ndat=length(res(1,:));
            newtracks=res;


            %u=unq(newtracks(:,ndat));

            % inserting unq
            indices = find(newtracks(:,ndat) ~= circshift(newtracks(:,ndat),-1));
            count = length(indices);
            if count > 0
                u = indices;
            else
                u = length(newtracks(:,ndat)) -1;
            end


            ntracks=length(u);
            u=[0;u];
            for i=2:ntracks+1
                newtracks(u(i-1)+1:u(i),ndat) = i-1;
            end

            % end of uberize code

            results = newtracks;


        end

        function [corrected_tracks,drift_profile] = drift_correction(app,input)
            tracks=input;
            % DISPLACEMENT_BASED_DRIFT_CORRECTION Handles missing steps through cumulative drift
            %   drift_profile: Contains cumulative drift from experiment start

            %% Step 1: Calculate mean displacements between adjacent steps
            [mean_dx, mean_dy, max_step] = calculate_mean_displacements(tracks);

            %% Step 2: Build cumulative drift profile
            [cumulative_x, cumulative_y] = build_cumulative_drift(mean_dx, mean_dy, max_step);

            %% Step 3: Apply corrections using cumulative drift
            corrected_tracks = apply_cumulative_correction(tracks, cumulative_x, cumulative_y);

            %% Store drift profile
            drift_profile = struct(...
                'cumulative_x', cumulative_x,...
                'cumulative_y', cumulative_y,...
                'max_step', max_step...
                );

            % Nested helper functions
            function [mean_dx, mean_dy, max_step] = calculate_mean_displacements(tracks)
                % Calculate mean displacements between consecutive steps
                displacement_map_x = containers.Map('KeyType','double','ValueType','any');
                displacement_map_y = containers.Map('KeyType','double','ValueType','any');
                all_steps = [];

                for t = 1:numel(tracks)
                    track = tracks{t};
                    steps = track(:,3);
                    all_steps = [all_steps; steps];

                    if numel(steps) < 2, continue; end

                    % Get consecutive steps and their displacements
                    valid = diff(steps) == 1;
                    from_steps = steps(1:end-1);
                    valid_from = from_steps(valid);
                    dx = diff(track(:,1));
                    dy = diff(track(:,2));

                    for s = 1:numel(valid_from)
                        step = valid_from(s);
                        if ~displacement_map_x.isKey(step)
                            displacement_map_x(step) = [];
                            displacement_map_y(step) = [];
                        end
                        displacement_map_x(step) = [displacement_map_x(step); dx(s)];
                        displacement_map_y(step) = [displacement_map_y(step); dy(s)];
                    end
                end

                % Calculate mean displacements
                mean_dx = containers.Map('KeyType','double','ValueType','double');
                mean_dy = containers.Map('KeyType','double','ValueType','double');
                steps = displacement_map_x.keys;
                for step = steps
                    step = step{1};
                    mean_dx(step) = mean(displacement_map_x(step));
                    mean_dy(step) = mean(displacement_map_y(step));
                end

                max_step = max(all_steps);
            end

            function [cumulative_x, cumulative_y] = build_cumulative_drift(mean_dx, mean_dy, max_step)
                % Build cumulative drift arrays from step 1 to max_step
                cumulative_x = zeros(max_step, 1);
                cumulative_y = zeros(max_step, 1);

                for step = 2:max_step
                    prev_step = step - 1;
                    if mean_dx.isKey(prev_step)
                        cumulative_x(step) = cumulative_x(prev_step) + mean_dx(prev_step);
                        cumulative_y(step) = cumulative_y(prev_step) + mean_dy(prev_step);
                    else
                        cumulative_x(step) = cumulative_x(prev_step);
                        cumulative_y(step) = cumulative_y(prev_step);
                    end
                end
            end

            function corrected_tracks = apply_cumulative_correction(tracks, cumulative_x, cumulative_y)
                % Apply correction using cumulative drift values
                corrected_tracks = cell(size(tracks));

                for t = 1:numel(tracks)
                    track = tracks{t};
                    steps = track(:,3);
                    if isempty(steps)
                        corrected_tracks{t} = track;
                        continue;
                    end

                    start_step = steps(1);
                    ref_x = cumulative_x(start_step);
                    ref_y = cumulative_y(start_step);

                    corrected_track = track;
                    for s = 1:numel(steps)
                        current_step = steps(s);
                        if current_step > length(cumulative_x)
                            continue; % Handle steps beyond max_step
                        end
                        corrected_track(s,1) = track(s,1) - (cumulative_x(current_step) - ref_x);
                        corrected_track(s,2) = track(s,2) - (cumulative_y(current_step) - ref_y);
                    end

                    corrected_tracks{t} = corrected_track;
                end
            end
        end

        function corrected_tracks = apply_drift_correction(app,tracks, drift_profile)
            % APPLY_DRIFT_CORRECTION Uses drift_profile to remove stage motion
            corrected_tracks = cell(size(tracks));
            for t = 1:numel(tracks)
                track = tracks{t};
                steps = track(:,3);
                x = track(:,1);
                y = track(:,2);

                start_step = steps(1);
                ref_x = drift_profile.cumulative_x(start_step);
                ref_y = drift_profile.cumulative_y(start_step);

                % Calculate relative drift
                rel_drift_x = arrayfun(@(s) drift_profile.cumulative_x(s) - ref_x, steps);
                rel_drift_y = arrayfun(@(s) drift_profile.cumulative_y(s) - ref_y, steps);

                % Apply correction
                corrected_track = track;
                corrected_track(:,1) = x - rel_drift_x;
                corrected_track(:,2) = y - rel_drift_y;

                corrected_tracks{t} = corrected_track;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
        end

        % Button pushed function: btnLoadFile
        function btnLoadFileButtonPushed(app, event)
            % Open files of the selected of file type
            switch app.btgFileTypes.SelectedObject
                case app.optND2 % single ND2 file
                    file_type_filter='*.nd2';
                    blnMultiSelect='off';
                case app.optFITS % a series of FITS files
                    file_type_filter='*.fits';
                    blnMultiSelect='on';
            end

            [file,path]=uigetfile(file_type_filter,'Select time-lapse file(s)',app.CurrentPath,'MultiSelect',blnMultiSelect);


            % Determine if the file(s) is/are successfully opened
            blnFileSuccess=false;
            switch class(file)
                case 'char' % a single file is specified
                    switch app.btgFileTypes.SelectedObject
                        case app.optND2
                            blnFileSuccess=true;
                            app.R=bfGetReader(fullfile(path,file));
                            app.txtMessage.Value=join(["Successfully opened ", fullfile(path,file)]);
                        case app.optFITS
                            blnFileSuccess=true;
                            app.R=cell(1,2);
                            app.txtMessage.Value=join(["Successfully opened ", fullfile(path,file)]);
                            app.R{1,1}=bfGetReader(fullfile(path,file));

                            % The 2nd column stores the number of planes in
                            % each FITS file. This is known from meta.
                            meta=app.R{1,1}.getMetadataStore();
                            meta_struct=xml2struct(meta.dumpXML());
                            app.R{1,2}=str2double(meta_struct.OME.Image.Pixels.Attributes.SizeZ);
                    end

                    app.CurrentPath=path;
                case 'cell' % multiple files selected
                    switch app.btgFileTypes.SelectedObject
                        case app.optND2
                            % This case is not likely because I set the
                            % MultiSelect option off for this case. But in
                            % case I made mistakes...
                            app.txtMessage.Value="Please open one single ND2 file.";

                        case app.optFITS
                            blnFileSuccess=true;
                            app.R=cell(length(file),2);
                            for i=1:length(file)

                                app.txtMessage.Value=[app.txtMessage.Value;...
                                    join(["Successfully opened ", fullfile(path,file)])];
                                app.R{i,1}=bfGetReader(fullfile(path,file{i}));

                                % The 2nd column stores the number of planes in
                                % each FITS file. This is known from meta.
                                meta=app.R{i,1}.getMetadataStore();
                                meta_struct=xml2struct(meta.dumpXML());
                                app.R{i,2}=str2double(meta_struct.OME.Image.Pixels.Attributes.SizeZ);

                            end
                            app.CurrentPath=path;
                    end

                case 'double' % no file selected
                    app.txtMessage.Value="No file selected.";
            end

            % Process the opened file for meta information
            if blnFileSuccess
                switch app.btgFileTypes.SelectedObject
                    case app.optND2
                        blnMetaSuccess=true;
                        try

                            % get meta information in OME format and
                            % convert into struct.
                            meta=app.R.getMetadataStore();
                            meta_struct=xml2struct(meta.dumpXML());

                            % common information for all planes
                            ObjectiveModel=meta_struct.OME.Instrument.Objective.Attributes.Model;
                            ObjectiveNA=meta_struct.OME.Instrument.Objective.Attributes.LensNA;
                            ObjectiveRI=meta_struct.OME.Image.ObjectiveSettings.Attributes.RefractiveIndex;
                            ChannelName=meta_struct.OME.Image.Pixels.Channel.Attributes.Name;
                            ChannelEm=[meta_struct.OME.Image.Pixels.Channel.Attributes.EmissionWavelength,' ',...
                                meta_struct.OME.Image.Pixels.Channel.Attributes.EmissionWavelengthUnit];
                            Res=[meta_struct.OME.Image.Pixels.Attributes.SizeX,'×',...
                                meta_struct.OME.Image.Pixels.Attributes.SizeY];
                            N_frame=meta_struct.OME.Image.Pixels.Attributes.SizeT;
                            BitDepth=meta_struct.OME.Image.Pixels.Attributes.SignificantBits;
                            CalibratedResX=[meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeX,' ',...
                                meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeXUnit];
                            app.PixelSize=double(string(meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeX));
                            app.PixelSizeUnit=string(meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeXUnit);
                            CalibratedResY=[meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeY,' ',...
                                meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeYUnit];

                            % plane-wise meta information will be averaged
                            % over all planes
                            planes=meta_struct.OME.Image.Pixels.Plane;
                            DeltaT_raw=zeros(length(planes),1);
                            ExpT_raw=zeros(length(planes),1);
                            PositionX_raw=zeros(length(planes),1);
                            PositionY_raw=zeros(length(planes),1);
                            PositionZ_raw=zeros(length(planes),1);
                            DeltaTUnit=planes{1}.Attributes.DeltaTUnit;
                            ExpTUnit=planes{1}.Attributes.ExposureTimeUnit;
                            PositionXUnit=planes{1}.Attributes.PositionXUnit;
                            PositionYUnit=planes{1}.Attributes.PositionYUnit;
                            PositionZUnit=planes{1}.Attributes.PositionZUnit;

                            for i=1:length(planes)
                                plane=planes{i}.Attributes;
                                DeltaT_raw(i)=str2double(plane.DeltaT);
                                ExpT_raw(i)=str2double(plane.ExposureTime);
                                PositionX_raw(i)=str2double(plane.PositionX);
                                PositionY_raw(i)=str2double(plane.PositionY);
                                PositionZ_raw(i)=str2double(plane.PositionZ);

                            end

                            % means and 95% confidence intervals
                            meanDeltaT=mean(gradient(DeltaT_raw));
                            [~,~,ci,~]=ttest(gradient(DeltaT_raw));
                            ci_DeltaT=meanDeltaT-ci(1);
                            app.dt=meanDeltaT;

                            meanExpT=mean(ExpT_raw);
                            [~,~,ci,~]=ttest(ExpT_raw);
                            ci_ExpT=meanExpT-ci(1);

                            meanPositionX=mean(PositionX_raw);
                            [~,~,ci,~]=ttest(PositionX_raw);
                            ci_PositionX=meanPositionX-ci(1);

                            meanPositionY=mean(PositionY_raw);
                            [~,~,ci,~]=ttest(PositionY_raw);
                            ci_PositionY=meanPositionY-ci(1);

                            meanPositionZ=mean(PositionZ_raw);
                            [~,~,ci,~]=ttest(PositionZ_raw);
                            ci_PositionZ=meanPositionZ-ci(1);

                            % Construct strings for table display
                            DeltaT=join([num2str(meanDeltaT),"±",num2str(ci_DeltaT)],'');
                            ExpT=join([num2str(meanExpT),"±",num2str(ci_ExpT)],'');
                            PositionX=join([num2str(meanPositionX),"±",num2str(ci_PositionX)],'');
                            PositionY=join([num2str(meanPositionY),"±",num2str(ci_PositionY)],'');
                            PositionZ=join([num2str(meanPositionZ),"±",num2str(ci_PositionZ)],'');


                            % update UITable control
                            ColumnNames=["Objective model";"N.A.";"Reflective index";...
                                "Channel";"Emission wavelength";...
                                "Resolution";"# frames";"Bit depth";...
                                "Pixel size X";"Pixel size Y";...
                                join(["Δt (", string(DeltaTUnit),")"],'');...
                                join(["Exposure time (",string(ExpTUnit),")"],'');...
                                join(["Stage X position (",string(PositionXUnit),")"],'');...
                                join(["Stage Y position (",string(PositionYUnit),")"],'');...
                                join(["Stage Z position (",string(PositionZUnit),")"],'')];
                            ColumnValues=[string(ObjectiveModel);string(ObjectiveNA);string(ObjectiveRI);...
                                string(ChannelName);string(ChannelEm);...
                                string(Res);string(N_frame);string(BitDepth);...
                                string(CalibratedResX);string(CalibratedResY);...
                                DeltaT;ExpT;PositionX;PositionY;PositionZ];
                            meta_list=table(ColumnNames,ColumnValues,'VariableNames',["Key","Value"]);

                            app.tblMetaList.Data=meta_list;
                            app.tblMetaList.ColumnName={"Key","Value"};
                            app.tblMetaList.ColumnSortable=[false,false];
                            app.tblMetaList.ColumnEditable=[false,false];
                            app.tblMetaList.ColumnRearrangeable=false;

                            % now the total number of frame is known
                            app.N_frame=str2double(N_frame);

                        catch ME
                            switch ME.identifier
                                case 'MATLAB:nonExistentField'
                                    % uialert(app.UIPanel,'Improper axial test data.','Error','Icon','error');
                                    % app.lblStatus.Text="Improper data.";
                                    app.txtMessage.Value=["Improper data.";...
                                        ME.message];
                                otherwise
                                    % uialert(app.UIPanel,ME.message,'Error','Icon','error');
                                    % app.lblStatus.Text=ME.message;
                                    app.txtMessage.Value=ME.message;
                            end
                            blnMetaSuccess=false;
                        end

                    case app.optFITS
                        blnMetaSuccess=true;
                        try
                            % Most useful data are retrieved via fitinfo
                            N_frame=0;

                            if iscell(file) % if multiple FITS files are opened
                                DeltaT_raw=zeros(length(file),1);
                                ExpT_raw=zeros(length(file),1);

                                for i=1:length(file)
                                    info=fitsinfo(fullfile(path,file{i}));


                                    Res=[num2str(info.PrimaryData.Size(2)),'×',...
                                        num2str(info.PrimaryData.Size(1))];
                                    % The size property is about the matrix
                                    % of the intensity profile. MATLAB
                                    % store the horizontal pixels as
                                    % columnes and the vertical pixels as
                                    % rows
                                    if length(info.PrimaryData.Size)>2

                                        N_frame=N_frame+info.PrimaryData.Size(3);
                                    else
                                        N_frame=N_frame+1;
                                    end

                                    kw=info.PrimaryData.Keywords;
                                    BitDepth=kw{strcmp(kw(:,1),'BITPIX'),2};
                                    try
                                        DeltaT_raw(i)=kw{strcmp(kw(:,1),'KCT'),2};
                                        ExpT_raw(i)=kw{strcmp(kw(:,1),'EXPOSURE'),2};
                                        blnHasTime=true;
                                    catch ME
                                        switch ME.identifier
                                            case 'MATLAB:TooManyOutputsDueToMissingBraces'
                                                % no dt and t_exp data
                                                blnHasTime=false;
                                                % prompt={'Enter shutter time (s):','Enter exposure time (s)'};
                                                % dlgtitle='Input';
                                                % fieldsize=[1,10;1,10];
                                                % definput={'0.01','0.01'};
                                                % answer=inputdlg(prompt,dlgtitle,fieldsize,definput);

                                        end

                                    end

                                end
                                if blnHasTime
                                    % means and 95% confident intervals among
                                    % FITS files
                                    meanDeltaT=mean(DeltaT_raw);
                                    [~,~,ci,~]=ttest(DeltaT_raw);
                                    ci_DeltaT=meanDeltaT-ci(1);
                                    app.dt=meanDeltaT;

                                    meanExpT=mean(ExpT_raw);
                                    [~,~,ci,~]=ttest(ExpT_raw);
                                    ci_ExpT=meanExpT-ci(1);

                                    DeltaT=join([num2str(meanDeltaT),"±",num2str(ci_DeltaT)],'');
                                    ExpT=join([num2str(meanExpT),"±",num2str(ci_ExpT)],'');
                                else
                                    DeltaT="N/A";
                                    ExpT="N/A";
                                    meanDeltaT=[];
                                    meanExpT=[];
                                end
                            else % same process for single FITS file
                                info=fitsinfo(fullfile(path,file));
                                Res=[num2str(info.PrimaryData.Size(1)),'×',...
                                    num2str(info.PrimaryData.Size(2))];
                                % if length(info.PrimaryData.Size)>2

                                N_frame=info.PrimaryData.Size(3);
                                DeltaT_raw=zeros(1,1);
                                ExpT_raw=zeros(1,1);


                                % else
                                %     N_frame=1;
                                % end
                                kw=info.PrimaryData.Keywords;
                                BitDepth=kw{strcmp(kw(:,1),'BITPIX'),2};
                                try
                                    DeltaT_raw=kw{strcmp(kw(:,1),'KCT'),2};
                                    ExpT_raw=kw{strcmp(kw(:,1),'EXPOSURE'),2};
                                    blnHasTime=true;
                                catch ME
                                    switch ME.identifier
                                        case 'MATLAB:TooManyOutputsDueToMissingBraces'
                                            % no dt and t_exp data
                                            blnHasTime=false;
                                            % prompt={'Enter shutter time (s):','Enter exposure time (s)'};
                                            % dlgtitle='Input';
                                            % fieldsize=[1,10;1,10];
                                            % definput={'0.01','0.01'};
                                            % answer=inputdlg(prompt,dlgtitle,fieldsize,definput);

                                    end

                                end
                                if blnHasTime
                                    % means and 95% confident intervals among
                                    % FITS files
                                    meanDeltaT=kw{strcmp(kw(:,1),'KCT'),2};
                                    app.dt=meanDeltaT;
                                    meanExpT=kw{strcmp(kw(:,1),'EXPOSURE'),2};
                                    DeltaT=num2str(meanDeltaT);
                                    ExpT=num2str(meanExpT);
                                else
                                    DeltaT="N/A";
                                    ExpT="N/A";
                                    meanDeltaT=[];
                                    meanExpT=[];
                                end

                            end

                            app.PixelSize=[];
                            app.PixelSizeUnit=[];

                            % update UITable control

                            ColumnNames=["Resolution";"# frames";"Bit depth";...
                                "Δt (s)";...
                                "Exposure time (s)";...
                                ];
                            ColumnValues=[string(Res);string(N_frame);string(BitDepth);...
                                DeltaT;ExpT];


                            % In case that some meta info needs to obtained
                            % by OME format
                            meta=app.R{1}.getMetadataStore();
                            meta_struct=xml2struct(meta.dumpXML());

                            meta_list=table(ColumnNames,ColumnValues,'VariableNames',["Key","Value"]);

                            app.tblMetaList.Data=meta_list;
                            app.tblMetaList.ColumnName={"Key","Value"};
                            app.tblMetaList.ColumnSortable=[false,false];
                            app.tblMetaList.ColumnEditable=[false,false];
                            app.tblMetaList.ColumnRearrangeable=false;

                            % now the total number of frame is known
                            app.N_frame=N_frame;
                        catch ME


                            app.txtMessage.Value=ME.message;
                            blnMetaSuccess=false;
                            switch ME.identifier
                                case'MATLAB:badsubscript'
                                    % only one frame
                                    h=errordlg("Cannot process a single frame.","Error",'modal');
                                    uiwait(h);
                            end

                        end
                end

                % Update other UI controls according to the info of opened
                % file(s)
                if blnMetaSuccess
                    app.sldFrame.Value=1;
                    if app.N_frame>1
                        app.sldFrame.Limits=[1,app.N_frame];
                        app.spnFrame.Value=1;
                        app.spnFrame.Limits=[1,app.N_frame];
                    else
                        app.sldFrame.Enable="off";
                    end

                    app.edtBPassDia.Limits=[0,...
                        str2double(meta_struct.OME.Image.Pixels.Attributes.SizeX)];
                    app.edtPkfndDia.Limits=[2,...
                        str2double(meta_struct.OME.Image.Pixels.Attributes.SizeX)];

                    app.edtMaxDisp.Limits=[0,str2double(meta_struct.OME.Image.Pixels.Attributes.SizeX)];
                    app.edtMem.Limits=[0,app.N_frame-1];
                    if app.N_frame>=2
                    app.edtGood.Limits=[2,app.N_frame];
                    else
                        app.edtGood.Enable="off";
                    end

                    % clear all old data
                    cla(app.ax);
                    app.current_img=[];
                    app.tr=[];
                    app.poslst=[];
                    app.chkBPass.Value=false;
                    app.chkPkfnd.Value=false;

                    app.replot
                end

            end


            % switch class(file)
            %     case  'char' % a file is specified
            %         app.CurrentPath=path;
            %         blnFileSuccess=true;
            %
            %
            %         % identifies XML parsing failure
            %         % blnDataSuccess=true; % identifies rheometric data mismatch
            %
            %         % Handles ND2 loading error
            %         try
            %             app.R=bfGetReader(fullfile(path,file));
            %         catch ME
            %             % uialert(app.UIPanel,ME.message,'Error','Icon','error')
            %             % app.lblStatus.Text=ME.message;
            %             app.txtMessage.Value=ME.message;
            %             blnND2Success=false;
            %         end
            %
            %         if blnND2Success
            %             % retrieve selected meta information from the nd2 file
            %             blnDataSuccess=true;
            %             try
            %                 % get ND2 meta infromation in the from of OME
            %                 % format and convert into struct
            %                 meta=app.R.getMetadataStore();
            %                 meta_struct=xml2struct(meta.dumpXML());
            %
            %                 ObjectiveModel=meta_struct.OME.Instrument.Objective.Attributes.Model;
            %                 % ObjectiveMagn=meta_struct.OME.Instrument.Objective.Attributes.CalibratedMagnification;
            %                 ObjectiveNA=meta_struct.OME.Instrument.Objective.Attributes.LensNA;
            %                 ObjectiveRI=meta_struct.OME.Image.ObjectiveSettings.Attributes.RefractiveIndex;
            %                 ChannelName=meta_struct.OME.Image.Pixels.Channel.Attributes.Name;
            %                 ChannelEm=[meta_struct.OME.Image.Pixels.Channel.Attributes.EmissionWavelength,' ',...
            %                     meta_struct.OME.Image.Pixels.Channel.Attributes.EmissionWavelengthUnit];
            %                 Res=[meta_struct.OME.Image.Pixels.Attributes.SizeX,'×',...
            %                     meta_struct.OME.Image.Pixels.Attributes.SizeY];
            %                 N_frame=meta_struct.OME.Image.Pixels.Attributes.SizeT;
            %                 BitDepth=meta_struct.OME.Image.Pixels.Attributes.SignificantBits;
            %                 CalibratedResX=[meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeX,' ',...
            %                     meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeXUnit];
            %                 CalibratedResY=[meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeY,' ',...
            %                     meta_struct.OME.Image.Pixels.Attributes.PhysicalSizeYUnit];
            %
            %
            %                 planes=meta_struct.OME.Image.Pixels.Plane;
            %                 DeltaT_raw=zeros(length(planes),1);
            %                 ExpT_raw=zeros(length(planes),1);
            %                 PositionX_raw=zeros(length(planes),1);
            %                 PositionY_raw=zeros(length(planes),1);
            %                 PositionZ_raw=zeros(length(planes),1);
            %                 DeltaTUnit=planes{1}.Attributes.DeltaTUnit;
            %                 ExpTUnit=planes{1}.Attributes.ExposureTimeUnit;
            %                 PositionXUnit=planes{1}.Attributes.PositionXUnit;
            %                 PositionYUnit=planes{1}.Attributes.PositionYUnit;
            %                 PositionZUnit=planes{1}.Attributes.PositionZUnit;
            %
            %
            %                 % recorded_data=table('Size',[length(planes),10],...
            %                 %     'VariableTypes',...
            %                 %     ["double","string","double","string","double","string","double","string",...
            %                 %     "double","string"],...
            %                 %     'VariableNames',...
            %                 %     ["Time stamp","Time stamp unit","Exposure time","Exposure time unit",...
            %                 %     "Position X","Position X unit","Position Y","Position Y unit",...
            %                 %     "Position Z","Position Z unit"]);
            %                 for i=1:length(planes)
            %                     plane=planes{i}.Attributes;
            %                     DeltaT_raw(i)=str2double(plane.DeltaT);
            %                     ExpT_raw(i)=str2double(plane.ExposureTime);
            %                     PositionX_raw(i)=str2double(plane.PositionX);
            %                     PositionY_raw(i)=str2double(plane.PositionY);
            %                     PositionZ_raw(i)=str2double(plane.PositionZ);
            %
            %
            %                     % recorded_data(i,["Time stamp","Time stamp unit","Exposure time","Exposure time unit",...
            %                     %     "Position X","Position X unit","Position Y","Position Y unit",...
            %                     %     "Position Z","Position Z unit"])=...
            %                     %     {str2double(plane.DeltaT),...
            %                     %     string(plane.DeltaTUnit),...
            %                     %     str2double(plane.ExposureTime),...
            %                     %     string(plane.ExposureTimeUnit),...
            %                     %     str2double(plane.PositionX),...
            %                     %     string(plane.PositionXUnit),...
            %                     %     str2double(plane.PositionY),...
            %                     %     string(plane.PositionYUnit),...
            %                     %     str2double(plane.PositionZ),...
            %                     %     string(plane.PositionZUnit)};
            %                 end
            %
            %                 meanDeltaT=mean(gradient(DeltaT_raw));
            %                 [~,~,ci,~]=ttest(gradient(DeltaT_raw));
            %                 ci_DeltaT=meanDeltaT-ci(1);
            %                 app.dt=meanDeltaT;
            %
            %                 meanExpT=mean(ExpT_raw);
            %                 [~,~,ci,~]=ttest(ExpT_raw);
            %                 ci_ExpT=meanExpT-ci(1);
            %
            %                 meanPositionX=mean(PositionX_raw);
            %                 [~,~,ci,~]=ttest(PositionX_raw);
            %                 ci_PositionX=meanPositionX-ci(1);
            %
            %                 meanPositionY=mean(PositionY_raw);
            %                 [~,~,ci,~]=ttest(PositionY_raw);
            %                 ci_PositionY=meanPositionY-ci(1);
            %
            %                 meanPositionZ=mean(PositionZ_raw);
            %                 [~,~,ci,~]=ttest(PositionZ_raw);
            %                 ci_PositionZ=meanPositionZ-ci(1);
            %
            %                 DeltaT=join([num2str(meanDeltaT),"±",num2str(ci_DeltaT)],'');
            %                 ExpT=join([num2str(meanExpT),"±",num2str(ci_ExpT)],'');
            %                 PositionX=join([num2str(meanPositionX),"±",num2str(ci_PositionX)],'');
            %                 PositionY=join([num2str(meanPositionY),"±",num2str(ci_PositionY)],'');
            %                 PositionZ=join([num2str(meanPositionZ),"±",num2str(ci_PositionZ)],'');
            %
            %
            %
            %
            %                 ColumnNames=["Objective model";"N.A.";"Reflective index";...
            %                     "Channel";"Emission wavelength";...
            %                     "Resolution";"# frames";"Bit depth";...
            %                     "Pixel size X";"Pixel size Y";...
            %                     join(["Δt (", string(DeltaTUnit),")"],'');...
            %                     join(["Exposure time (",string(ExpTUnit),")"],'');...
            %                     join(["Stage X position (",string(PositionXUnit),")"],'');...
            %                     join(["Stage Y position (",string(PositionYUnit),")"],'');...
            %                     join(["Stage Z position (",string(PositionZUnit),")"],'')];
            %                 ColumnValues=[string(ObjectiveModel);string(ObjectiveNA);string(ObjectiveRI);...
            %                     string(ChannelName);string(ChannelEm);...
            %                     string(Res);string(N_frame);string(BitDepth);...
            %                     string(CalibratedResX);string(CalibratedResY);...
            %                     DeltaT;ExpT;PositionX;PositionY;PositionZ];
            %
            %                 meta_list=table(ColumnNames,ColumnValues,'VariableNames',["Key","Value"]);
            %
            %                 app.tblMetaList.Data=meta_list;
            %                 app.tblMetaList.ColumnName={"Key","Value"};
            %                 app.tblMetaList.ColumnSortable=[false,false];
            %                 app.tblMetaList.ColumnEditable=[false,false];
            %                 app.tblMetaList.ColumnRearrangeable=false;
            %
            %                 % app.tblRecordedData.Data=recorded_data;
            %                 % app.tblRecordedData.ColumnName=["Time stamp","Time stamp unit","Exposure time","Exposure time unit",...
            %                 %     "Position X","Position X unit","Position Y","Position Y unit",...
            %                 %     "Position Z","Position Z unit"];
            %                 % app.tblRecordedData.ColumnSortable=false(1,10);
            %                 % app.tblRecordedData.ColumnEditable=false(1,10);
            %                 % app.tblRecordedData.ColumnRearrangeable=false;
            %
            %             catch ME
            %
            %                 switch ME.identifier
            %                     case 'MATLAB:nonExistentField'
            %                         % uialert(app.UIPanel,'Improper axial test data.','Error','Icon','error');
            %                         % app.lblStatus.Text="Improper data.";
            %                         app.txtMessage.Value="Improper data.";
            %                     case ''
            %                         % uialert(app.UIPanel,ME.message,'Error','Icon','error');
            %                         % app.lblStatus.Text=ME.message;
            %                         app.txtMessage.Value=ME.message;
            %                 end
            %                 blnDataSuccess=false;
            %             end
            %         end
            %
            %     case 'double' % no file selected
            %         % uialert(app.UIPanel,'No file selected.','Canceled','Icon','info');
            %         % app.lblStatus.Text="No file selected.";
            %         app.txtMessage.Value="No file selected.";
            %         blnND2Success=false;
            % end


            % if blnND2Success && blnDataSuccess
            %     % app.lblStatus.Text="Ready.";
            %     app.txtMessage.Value="Ready.";
            %     % preview_win(app,app.R,str2double(N_frame));
            %
            %
            %     app.N_frame=str2double(N_frame);
            %
            %     % update UI controls
            %     app.sldFrame.Value=1;
            %     app.sldFrame.Limits=[1,app.N_frame];
            %     app.edtFrame.Value=1;
            %     app.edtFrame.Limits=[1,app.N_frame];
            %
            %     app.edtBPassDia.Limits=[0,...
            %         str2double(meta_struct.OME.Image.Pixels.Attributes.SizeX)];
            %
            %     app.edtPkfndDia.Limits=[2,...
            %         str2double(meta_struct.OME.Image.Pixels.Attributes.SizeX)];
            %
            %     app.edtMaxDisp.Limits=[0,str2double(meta_struct.OME.Image.Pixels.Attributes.SizeX)];
            %     app.edtMem.Limits=[0,app.N_frame-1];
            %     app.edtGood.Limits=[2,app.N_frame];
            %
            %
            %
            %     % retrieve the 1st plane
            %     % app.preview.Visible='on';
            %     % figure(app.preview);
            %     % app.preview.CloseRequestFcn=@close_preview;
            %     % app.ax.Parent=app.preview;
            %     % app.img=bfGetPlane(app.R,1);
            %     % max_intensity=double(max(app.img(:)));
            %     % imshow(app.img,[0,2.*max_intensity],'Parent',app.ax);
            %     % app.sldLevelRange.Limits=[0,2.*max_intensity];
            %     % app.sldLevelRange.Value=[0,2.*max_intensity];
            %
            %     cla(app.ax);
            %
            %     app.replot
            %
            %
            %
            % end

            % if blnXMLSuccess && blnDataSuccess
            %     % Tasks:
            %     % * Determines the sampling frequency of the data by the
            %     % SegmentTime and pre-determines the window size
            %     % * Update slider and text box properties and values
            %     % * Plot results
            %
            %     % sampling frequency of the data. Unit: Hz
            %     % sampling_freq=mean(diff(data_temp(:,1)))./length(data_temp(:,1));
            %
            %     % Speed of the upper geometry. Unit: mm/s
            %     % head_speed=gradient(data_temp(:,4))./gradient(data_temp(:,1));
            %
            %     % preset the window size according to the length of the data
            %     app.N_win=floor(length(data_temp(:,1))./100);
            %     if app.N_win==0
            %         app.N_win=1;
            %     end
            %
            %     % determine the maximum windows size according to the
            %     % length of the data
            %     app.max_N_win=floor(length(data_temp(:,1))./10);
            %
            %     % Update properties of the slider
            %     app.sldWindowSize.Limits=[1,app.max_N_win];
            %     app.sldWindowSize.Value=app.N_win;
            %     app.sldWindowSize.Tooltip=num2str(app.N_win);
            %
            %     % Update edit boxs
            %     app.spnWindowSize.Limits=[1,app.max_N_win];
            %     app.spnWindowSize.Value=app.N_win;
            %
            %
            %
            %
            %
            %
            %     % confirm the data
            %     app.dat=data_temp;
            %
            %     app.lblStatus.Text="";
            % end


            % if ~isempty(app.dat)
            %
            %     % analysis
            %     app.cal;
            %
            %
            %
            %
            %
            %
            %
            %
            % end
            %
            % bring the app window into current focus
            figure(app.UIPanel);
        end

        % Value changing function: sldFrame
        function sldFrameValueChanging(app, event)
            changingValue = event.Value;
            app.spnFrame.Value=changingValue;
        end

        % Value changed function: sldFrame
        function sldFrameValueChanged(app, event)
            value = app.sldFrame.Value;

            % must be integer
            value=floor(value);

            % update UI controls
            app.sldFrame.Value=value;
            app.spnFrame.Value=value;
            app.sldFrame.Tooltip=num2str(value);
            if ~isempty(app.current_img)
                app.replot;
            end


        end

        % Value changed function: sldLevelRange
        function sldLevelRangeValueChanged(app, event)
            value = app.sldLevelRange.Value;
            % must be integers
            value=floor(value);

            % update UI controls
            app.sldLevelRange.Value=value;
            app.sldLevelRange.Tooltip=[num2str(app.sldLevelRange.Value(1)),'~',num2str(app.sldLevelRange.Value(2))];
            if ~isempty(app.current_img)
                app.replot;
            end
        end

        % Value changed function: chkBPass
        function chkBPassValueChanged(app, event)
            % value = app.chkBPass.Value;
            if ~isempty(app.current_img)
                app.replot;
            end
        end

        % Value changed function: edtBPassDia
        function edtBPassDiaValueChanged(app, event)
            if ~isempty(app.current_img)
                app.replot
            end
        end

        % Value changed function: chkPkfnd
        function chkPkfndValueChanged(app, event)
            if ~isempty(app.current_img)
                app.replot
            end
        end

        % Value changed function: edtPkfndDia
        function edtPkfndDiaValueChanged(app, event)
            if ~isempty(app.current_img)
                app.replot
            end
        end

        % Value changed function: sldMinBrightness
        function sldMinBrightnessValueChanged(app, event)
            value = app.sldMinBrightness.Value;
            value=floor(value);
            app.sldMinBrightness.Value=value;
            app.sldMinBrightness.Tooltip=num2str(value);
            app.spnMinBrightness.Value=value;
            if ~isempty(app.current_img)
                app.replot
            end
        end

        % Button pushed function: btnPoslst
        function btnPoslstButtonPushed(app, event)
            if ~isempty(app.R)
                poslst=[];
                blnNoise=false;


                for i=1:app.N_frame

                    % get the intensity profiles of each plane
                    switch app.btgFileTypes.SelectedObject
                        case app.optND2
                            img=bfGetPlane(app.R,i);
                        case app.optFITS
                            % determine which cell the required frame is at and the
                            % corresponding index of plane in that cell
                            cumulativeSum=0;
                            k=NaN;
                            img_ind=NaN;
                            for j=1:numel(app.R)
                                if i<=cumulativeSum+app.R{j,2}
                                    k=j;
                                    img_ind=i-cumulativeSum;
                                    break;
                                end
                                cumulativeSum=cumulativeSum+app.R{j,2};
                            end
                            img=bfGetPlane(app.R{k,1},img_ind);
                            switch class(img)
                                case 'int16'
                                    img=uint16(img+32768);
                            end
                    end

                    % save the raw, un-bpassed image
                    img2=img;

                    % process the image
                    img=bpass(img,2,app.edtBPassDia.Value);
                    pk=pkfnd(img,app.sldMinBrightness.Value,app.edtPkfndDia.Value);

                    % diameter for cntrd should be slightly larger than
                    % that for pkfnd and odd
                    if app.edtPkfndDia.Value/2==floor(app.edtPkfndDia.Value/2)
                        cnt=cntrd(img,pk,app.edtPkfndDia.Value+3);
                    else
                        cnt=cntrd(img,pk,app.edtPkfndDia.Value+2);
                    end

                    if ~isempty(cnt)
                        cnt(isnan(cnt(:,1)),:)=[];
                    end

                    if ~isempty(pk)& ~isempty(cnt)
                        % record the central brightness data in the raw
                        % image
                        br=double(arrayfun(@(i)img2(round(cnt(i,2)),round(cnt(i,1))),(1:length(cnt(:,1)))','UniformOutput',1));
                        % append position data to poslst
                        poslst=[poslst;[cnt(:,1:2),ones(length(cnt(:,1)),1)*i,br]];
                    end

                    % profile the background noise of the image by
                    % excluding the particles from it
                    while ~isempty(pk) && ~blnNoise
                        pk_n=pk;
                        [m,n]=size(img2);
                        % diameter for cntrd should be slightly larger than
                        % that for pkfnd and odd
                        if app.edtPkfndDia.Value/2==floor(app.edtPkfndDia.Value/2)
                            d_cntrd=app.edtBPassDia.Value+3;
                        else
                            d_cntrd=app.edtBPassDia.Value+2;
                        end

                        % dig out the particles from the raw image by masks
                        % with 10 time larger diameters than d_cntrd
                        for o=1:length(pk_n(:,1))
                            img2=double(img2);
                            img2(max(1,pk_n(o,2)-round(d_cntrd*10/2)):min(m,pk_n(o,2)+round(d_cntrd*10/2)),...
                                max(1,pk_n(o,1)-round(d_cntrd*10/2)):min(n,pk_n(o,1)+round(d_cntrd*10/2)))...
                                =NaN;
                        end

                        % estimate the background noise level
                        img2=img2(:);
                        img2(isnan(img2))=[];
                        app.meanNoise=mean(img2);
                        app.stdNoise=std(img2);

                        % noise evaluation is done only one time
                        blnNoise=true;
                    end

                    app.txtMessage.Value=join([num2str(i)," frame(s) processed."]);
                    drawnow
                end

                % calculate the mean brightness of particles and the SNR
                app.br=mean(poslst(:,4));
                app.snr=10*log10(app.br/app.meanNoise);

                % store the poslst as global data
                app.poslst=poslst(:,1:3);
                app.txtMessage.Value=join(["Position list generated. ", ...
                    "Avg. brightness = ", num2str(app.br), ...
                    ", avg. noise = ", num2str(app.meanNoise), ...
                    ", noise std. = ", num2str(app.stdNoise),...
                    ", SNR = ",num2str(app.snr),"."]);
                % app.lblStatus.Text="Ready.";
                drawnow

                % save peak finding parameters
                app.blnBPass=app.chkBPass.Value;
                if app.blnBPass
                    app.BPassDia=app.edtBPassDia.Value;
                else
                    app.BPassDia=NaN;
                end
                app.PkfndDia=app.edtPkfndDia.Value;
                app.MinBrightness=app.sldMinBrightness.Value;


            else
                % app.lblStatus.Text="No ND2 file loaded.";
                app.txtMessage.Value="No time-lapse file loaded.";
            end
        end

        % Value changed function: edtMaxDisp
        function edtMaxDispValueChanged(app, event)
            value = app.edtMaxDisp.Value;
            value=floor(value);
            app.edtMaxDisp.Value=value;
        end

        % Value changed function: edtMem
        function edtMemValueChanged(app, event)
            value = app.edtMem.Value;
            value=floor(value);
            app.edtMem.Value=value;
        end

        % Value changed function: edtGood
        function edtGoodValueChanged(app, event)
            value = app.edtGood.Value;
            value=floor(value);
            app.edtGood.Value=value;
        end

        % Button pushed function: btnTrack
        function btnTrackButtonPushed(app, event)
            if isempty(app.poslst)
                app.txtMessage.Value="Locate all frames first.";
            else
                param=struct('mem',app.edtMem.Value,'dim',2,'good',app.edtGood.Value,'quiet',0);

                app.tr=app.track(app.poslst,app.edtMaxDisp.Value,param);

                % save tracking parameters
                if ~isempty(app.tr)
                    app.MaxDisp=app.edtMaxDisp.Value;
                    app.Memory=app.edtMem.Value;
                    app.MinLength=app.edtGood.Value;
                else
                    app.txtMessage.Value="No track.";
                    return;
                end

                % drift profiling


                tr=app.tr;
                ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                tr_temp=arrayfun(@(x,y) tr(x:y,:),ind(:,1),ind(:,2),'UniformOutput',0);
                if length(tr_temp)>1

                    % calculate the displacements between adjacent frames, and label them by
                    % staring_fr_id's and tr_id's. [dx,dy,starting_fr_id,tr_id]
                    drift=cellfun(@(x) [diff(x(:,1:3),1,1),x(1:end-1,3:4)],tr_temp,'UniformOutput',0);
                    drift=cellfun(@(x) x(x(:,3)==1,[1,2,4,5]),drift,'UniformOutput',0);

                    % group drift_temp into cell array by starting_fr_id's, and average the
                    % dispacements of each starting_fr_id. [mean_dx,mean_dy, starting_fr_id]
                    drift=sortrows(cell2mat(drift),[3,4]);
                    ind=[[1;find(diff(drift(:,3))~=0)+1],[find(diff(drift(:,3))~=0);length(drift(:,3))]];
                    drift=cell2mat(arrayfun(@(i,j) [mean(drift(i:j,1:2),1),drift(i,3)],ind(:,1),ind(:,2),'UniformOutput',0));

                    % zero-padding missing starting_fr_id by zero displacements
                    padded_drift=zeros(max(drift(:,3))-min(drift(:,3))+1,2);
                    step_ids=unique(drift(:,3));
                    for i=1:length(step_ids)
                        step_id=step_ids(i);
                        idx=drift(:,3)==step_id;
                        padded_drift(step_id-min(drift(:,3))+1,:)=drift(idx,1:2);
                    end
                    drift=[padded_drift,(min(drift(:,3)):max(drift(:,3)))'];
                    drift(:,1:2)=cumsum(drift(:,1:2),1);
                    drift=[[zeros(1,2),min(drift(:,3))-1];drift];
                    % now drift can serve as the drift profile
                    app.drift_profile=drift;
                else
                    app.drift_profile=[];
                end


                app.replot;
            end


        end

        % Button pushed function: btnSaveData
        function btnSaveDataPushed(app, event)
            app.txtMessage.Value="";

            if ~isempty(app.tblMetaList.Data)
                meta=app.tblMetaList.Data;
                dt=app.dt;
                PixelSize=app.PixelSize;
                PixelSizeUnit=app.PixelSizeUnit;
                % writetable(meta,fullfile(app.CurrentPath,"meta.xlsx"));
                % save(fullfile(app.CurrentPath,"meta.txt"),"meta",'-ascii');
                app.txtMessage.Value=[app.txtMessage.Value;...
                    "Meta data saved."];
            else
                meta=[];
                dt=[];
                PixelSize=[];
                PixelSizeUnit=[];
                app.txtMessage.Value=[app.txtMessage.Value;...
                    "No meta data saved."];
            end

            if ~isempty(app.poslst)
                blnBPass=app.blnBPass;
                BPassDia=app.BPassDia;
                PkfndDia=app.PkfndDia;
                MinBrightness=app.MinBrightness;
                br=app.br;
                meanNoise=app.meanNoise;
                stdNoise=app.stdNoise;
                poslst=app.poslst;
                snr=app.snr;
                app.txtMessage.Value=[app.txtMessage.Value;...
                    "Peak finding parameters & results saved."];
            else
                blnBPass=[];
                BPassDia=[];
                PkfndDia=[];
                MinBrightness=[];
                br=[];
                meanNoise=[];
                stdNoise=[];
                poslst=[];
                snr=[];
                app.txtMessage.Value=[app.txtMessage.Value;...
                    "No results of peak finding."];
            end


            if ~isempty(app.tr)
                MaxDisp=app.MaxDisp;
                Memory=app.Memory;
                MinLength=app.MinLength;
                tr=app.tr;

                if app.chkDriftCanceling.Value
                    % if drift canceling is checked save the corrected tracks instead
                    ind=[[1;find(diff(tr(:,4))~=0)+1],[find(diff(tr(:,4))~=0);length(tr(:,4))]];
                    tr_temp=arrayfun(@(x,y) tr(x:y,:),ind(:,1),ind(:,2),'UniformOutput',0);
                    drift=app.drift_profile;
                    for i=1:length(tr_temp)
                        t=tr_temp{i};
                        start_step=t(1,3);
                        for j=2:size(t,1)

                            step_id=t(j,3);
                            rel_drift=drift(step_id,1:2)-drift(start_step,1:2);
                            t(j,1:2)=t(j,1:2)-rel_drift;
                        end

                        tr_temp{i}=t;
                    end
                    tr=cell2mat(tr_temp);
                end


                % save(fullfile(app.CurrentPath,"tr.mat"),"tr","-mat");
                app.txtMessage.Value=[app.txtMessage.Value;...
                    "Tracking parameters & results saved"];
                % app.txtMessage.Value=[app.txtMessage.Value;...
                % save(fullfile(app.CurrentPath,"dat.mat"),"br","dt","N_fr","ns","poslst","snr","-mat");
                %     "dat.mat saved."];
            else
                MaxDisp=[];
                Memory=[];
                MinLength=[];
                tr=[];

                app.txtMessage.Value=[app.txtMessage.Value;...
                    "No tracking data saved."];
            end
            app.txtMessage.Value=[app.txtMessage.Value;...
                join(["File: ",fullfile(app.CurrentPath,"PT_output.mat")])];
            save(fullfile(app.CurrentPath,"PT_output.mat"),...
                "blnBPass","BPassDia","PkfndDia","MinBrightness",...
                "br","meanNoise","stdNoise","poslst","snr",...
                "MaxDisp","Memory","MinLength","tr",...
                "meta","dt","PixelSize","PixelSizeUnit",...
                "-mat");
        end

        % Value changing function: spnFrame
        function spnFrameValueChanging(app, event)
            changingValue = event.Value;
            changingValue=floor(changingValue);

            app.sldFrame.Value=changingValue;
        end

        % Value changed function: spnFrame
        function spnFrameValueChanged(app, event)
            value = app.spnFrame.Value;
            % must be integer
            value=floor(value);

            % update UI controls
            app.sldFrame.Value=value;
            app.sldFrame.Tooltip=num2str(value);
            if ~isempty(app.current_img)
                app.replot;
            end
        end

        % Value changed function: spnMinBrightness
        function spnMinBrightnessValueChanged(app, event)
            value = app.spnMinBrightness.Value;
            app.sldMinBrightness.Value=value;
            if ~isempty(app.current_img)
                app.replot
            end
        end

        % Value changing function: spnMinBrightness
        function spnMinBrightnessValueChanging(app, event)
            changingValue = event.Value;
            app.sldMinBrightness.Value=changingValue;
        end

        % Value changing function: sldMinBrightness
        function sldMinBrightnessValueChanging(app, event)
            changingValue = event.Value;
            app.spnMinBrightness.Value=changingValue;
        end

        % Button pushed function: btnClearTracks
        function btnClearTracksButtonPushed(app, event)
            app.tr=[];
            app.replot
        end

        % Value changed function: chkDriftCanceling
        function chkDriftCancelingValueChanged(app, event)
            value = app.chkDriftCanceling.Value;
            app.replot
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIPanel = uifigure('Visible', 'off');
            app.UIPanel.Name = 'Particle Tracking';
            app.GridLayout2 = uigridlayout(app.UIPanel);
            app.pnlLoadFile = uipanel(app.GridLayout2);
            app.pnlLoadFile.Title = 'Load file';
            app.loadFileGridLayout = uigridlayout(app.pnlLoadFile);
            app.btnLoadFile = uibutton(app.loadFileGridLayout, 'push');
            app.btnLoadFile.ButtonPushedFcn = createCallbackFcn(app, @btnLoadFileButtonPushed, true);
            app.btnLoadFile.Text = 'Load...';
            app.btgFileTypes = uibuttongroup(app.loadFileGridLayout);
            app.btgFileTypes.Title = 'File types';
            app.optND2 = uiradiobutton(app.btgFileTypes);
            app.optND2.Text = 'ND2';
            app.optND2.Value = true;
            app.optFITS = uiradiobutton(app.btgFileTypes);
            app.optFITS.Text = 'FITS';
            app.txtMessage = uitextarea(app.loadFileGridLayout);
            app.txtMessage.Editable = 'off';
            app.tblMetaList = uitable(app.loadFileGridLayout);
            app.tblMetaList.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.tblMetaList.ColumnRearrangeable = 'on';
            app.tblMetaList.RowName = {};
            app.pnlLocatingTracking = uipanel(app.GridLayout2);
            app.pnlLocatingTracking.Title = 'Locating & tracking options';
            app.locatingTrackingGridLayout = uigridlayout(app.pnlLocatingTracking);
            app.chkBPass = uicheckbox(app.locatingTrackingGridLayout);
            app.chkBPass.ValueChangedFcn = createCallbackFcn(app, @chkBPassValueChanged, true);
            app.chkBPass.Tooltip = {'Check to preview effect.'};
            app.chkBPass.Text = 'Band-pass filter';
            app.MaxDisppxLabel = uilabel(app.locatingTrackingGridLayout);
            app.MaxDisppxLabel.Text = 'Max. disp.:';
            app.edtMaxDisp = uieditfield(app.locatingTrackingGridLayout, 'numeric');
            app.edtMaxDisp.ValueDisplayFormat = '%.0f';
            app.edtMaxDisp.ValueChangedFcn = createCallbackFcn(app, @edtMaxDispValueChanged, true);
            app.edtMaxDisp.Tooltip = {'Maximum displacement (px) allowed for a particle.'};
            app.MemoryLabel = uilabel(app.locatingTrackingGridLayout);
            app.MemoryLabel.Text = 'Memory:';
            app.edtMem = uieditfield(app.locatingTrackingGridLayout, 'numeric');
            app.edtMem.ValueDisplayFormat = '%.0f';
            app.edtMem.ValueChangedFcn = createCallbackFcn(app, @edtMemValueChanged, true);
            app.edtMem.Tooltip = {'Maximum number of frames of disapearance for a particle to be remembered as the same particle.'};
            app.MinlengthLabel = uilabel(app.locatingTrackingGridLayout);
            app.MinlengthLabel.Text = 'Min. length';
            app.edtGood = uieditfield(app.locatingTrackingGridLayout, 'numeric');
            app.edtGood.ValueDisplayFormat = '%.0f';
            app.edtGood.ValueChangedFcn = createCallbackFcn(app, @edtGoodValueChanged, true);
            app.edtGood.Tooltip = {'Tracks shorter than this value (number of steps) will be discarded.'};
            app.edtGood.Value = 2;
            app.lblMinBrightness = uilabel(app.locatingTrackingGridLayout);
            app.lblMinBrightness.Text = 'Min brightness:';
            app.btnPoslst = uibutton(app.locatingTrackingGridLayout, 'push');
            app.btnPoslst.ButtonPushedFcn = createCallbackFcn(app, @btnPoslstButtonPushed, true);
            app.btnPoslst.Text = 'Locate all frames';
            app.btnTrack = uibutton(app.locatingTrackingGridLayout, 'push');
            app.btnTrack.ButtonPushedFcn = createCallbackFcn(app, @btnTrackButtonPushed, true);
            app.btnTrack.Text = 'Track';
            app.chkDriftCanceling = uicheckbox(app.locatingTrackingGridLayout);
            app.chkDriftCanceling.ValueChangedFcn = createCallbackFcn(app, @chkDriftCancelingValueChanged, true);
            app.chkDriftCanceling.Text = 'Stage motion canceling';
            app.btnClearTracks = uibutton(app.locatingTrackingGridLayout, 'push');
            app.btnClearTracks.ButtonPushedFcn = createCallbackFcn(app, @btnClearTracksButtonPushed, true);
            app.btnClearTracks.Text = 'Clear';
            app.btnSaveData = uibutton(app.locatingTrackingGridLayout, 'push');
            app.btnSaveData.ButtonPushedFcn = createCallbackFcn(app, @btnSaveDataPushed, true);
            app.btnSaveData.Text = 'Save';
            app.edtPkfndDia = uieditfield(app.locatingTrackingGridLayout, 'numeric');
            app.edtPkfndDia.Limits = [2 Inf];
            app.edtPkfndDia.ValueDisplayFormat = '%.0f';
            app.edtPkfndDia.ValueChangedFcn = createCallbackFcn(app, @edtPkfndDiaValueChanged, true);
            app.edtPkfndDia.Tooltip = {'Diameter (px) of the particles in the bpass-ed image.'};
            app.edtPkfndDia.Value = 2;
            app.edtBPassDia = uieditfield(app.locatingTrackingGridLayout, 'numeric');
            app.edtBPassDia.ValueDisplayFormat = '%.0f';
            app.edtBPassDia.ValueChangedFcn = createCallbackFcn(app, @edtBPassDiaValueChanged, true);
            app.edtBPassDia.Tooltip = {'Diameter (px) of particles in the raw image.'};
            app.spnMinBrightness = uispinner(app.locatingTrackingGridLayout);
            app.spnMinBrightness.Step = 0.1;
            app.spnMinBrightness.ValueChangingFcn = createCallbackFcn(app, @spnMinBrightnessValueChanging, true);
            app.spnMinBrightness.Limits = [0 Inf];
            app.spnMinBrightness.ValueDisplayFormat = '%.3f';
            app.spnMinBrightness.ValueChangedFcn = createCallbackFcn(app, @spnMinBrightnessValueChanged, true);
            app.spnMinBrightness.Tooltip = {'Cut off brightness level for peak finding.'};
            app.sldMinBrightness = uislider(app.locatingTrackingGridLayout);
            app.sldMinBrightness.ValueChangedFcn = createCallbackFcn(app, @sldMinBrightnessValueChanged, true);
            app.sldMinBrightness.ValueChangingFcn = createCallbackFcn(app, @sldMinBrightnessValueChanging, true);
            app.chkPkfnd = uicheckbox(app.locatingTrackingGridLayout);
            app.chkPkfnd.ValueChangedFcn = createCallbackFcn(app, @chkPkfndValueChanged, true);
            app.chkPkfnd.Tooltip = {'Check to preview effect.'};
            app.chkPkfnd.Text = 'Find peaks';
            app.RightPanel = uipanel(app.GridLayout2);
            app.RightPanel.Title = 'View';
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.ax = uiaxes(app.GridLayout3);
            app.ax.XTickLabel = '';
            app.ax.YTickLabel = '';
            app.spnFrame = uispinner(app.GridLayout3);
            app.spnFrame.ValueChangingFcn = createCallbackFcn(app, @spnFrameValueChanging, true);
            app.spnFrame.Limits = [1 Inf];
            app.spnFrame.RoundFractionalValues = 'on';
            app.spnFrame.ValueDisplayFormat = '%.0f';
            app.spnFrame.ValueChangedFcn = createCallbackFcn(app, @spnFrameValueChanged, true);
            app.spnFrame.Value = 1;
            app.FrameLabel = uilabel(app.GridLayout3);
            app.FrameLabel.Text = 'Frame:';
            app.sldFrame = uislider(app.GridLayout3);
            app.sldFrame.ValueChangedFcn = createCallbackFcn(app, @sldFrameValueChanged, true);
            app.sldFrame.ValueChangingFcn = createCallbackFcn(app, @sldFrameValueChanging, true);
            app.LevelrangeLabel = uilabel(app.GridLayout3);
            app.LevelrangeLabel.Text = 'Level range:';
            app.sldLevelRange = uislider(app.GridLayout3, 'range');
            app.sldLevelRange.ValueChangedFcn = createCallbackFcn(app, @sldLevelRangeValueChanged, true);
            % Show the figure after all components are created
            app.UIPanel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = particle_tracking

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIPanel)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIPanel)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIPanel)
        end
    end
end